classdef APECSgui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        popTools        matlab.ui.container.Menu
        pop2EDF         matlab.ui.container.Menu
        popSaveWeights  matlab.ui.container.Menu
        setWorkspace    matlab.ui.container.Menu
        GridLayout      matlab.ui.container.GridLayout
        tip_box         matlab.ui.control.Label
        runGenFeatures  matlab.ui.control.StateButton
        runPreProcess   matlab.ui.control.StateButton
        runGenSet       matlab.ui.control.StateButton
        runApp          matlab.ui.control.StateButton
        Image           matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function APECSgui_OpeningFcn(app, varargin)

            
            % Ensure that the app appears on screen when run
            %movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            %set(0,'Units','normalized');
            %set(handles.figure1,'Position',[1 1 718 540]);
            clc
            addpath(genpath(pwd))


            % Choose default command line output for APECSgui
            %handles.output = hObject;
            
            % Update handles structure
            %guidata(hObject, handles);


        end

        % Menu selected function: pop2EDF
        function pop2EDF_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pop2EDF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            gui_2EDF_App;
            
            %guidata(hObject,handles)
        end

        % Menu selected function: popSaveWeights
        function popSaveWeights_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popSaveWeights (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            gui_BCI2000_App;
            
            %guidata(hObject,handles)
        end

        % Value changed function: runApp
        function runApp_Callback(app, event)
            % --- Executes on button press in runApp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to runApp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %global mainFig
            
            %set(handles.runApp,'Value',0);
            app.runApp.Value=0;
            
            gui_runApp_App;
            
            %guidata(hObject,handles)
        end

        % Value changed function: runGenFeatures
        function runGenFeatures_Callback(app, event)
            % --- Executes on button press in runGenFeatures.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to runGenFeatures (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %global mainFig
            
            %set(handles.runGenFeatures,'Value',0);
            app.runGenFeatures.Value=0;
            
            gui_genFeatures_App;
            
            %guidata(hObject,handles)
        end

        % Value changed function: runGenSet
        function runGenSet_Callback(app, event)
            % --- Executes on button press in runGenSet.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to runGenSet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %global mainFig
            
            %set(handles.runGenSet,'Value',0);
            app.runGenSet.Value=0;
            
            gui_genSets_App;
            
            %guidata(hObject,handles)
        end

        % Value changed function: runPreProcess
        function runPreProcess_Callback(app, event)
            % --- Executes on button press in runPreProcess.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to runPreProcess (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %set(handles.runPreProcess,'Value',0);
            app.runPreProcess.Value = 0;
            %set(handles.runGenSet,'Enable','off');
            
            %uiopen('M-files/coreGUI/gui_preProcessRaw_App.mlapp',1);
            gui_preProcessRaw_App;


            %guidata(hObject,handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'APECSgui';
            app.figure1.Tag = 'figure1';
            app.popTools = uimenu(app.figure1);
            app.popTools.Text = 'Tools';
            app.popTools.Tag = 'popTools';
            app.pop2EDF = uimenu(app.popTools);
            app.pop2EDF.MenuSelectedFcn = createCallbackFcn(app, @pop2EDF_Callback, true);
            app.pop2EDF.Text = '2EDF';
            app.pop2EDF.Tag = 'pop2EDF';
            app.popSaveWeights = uimenu(app.popTools);
            app.popSaveWeights.MenuSelectedFcn = createCallbackFcn(app, @popSaveWeights_Callback, true);
            app.popSaveWeights.Text = 'Save weights (BCI2000, xls) ';
            app.popSaveWeights.Tag = 'popSaveWeights';
            app.setWorkspace = uimenu(app.popTools);
            app.setWorkspace.Separator = 'on';
            app.setWorkspace.Text = 'Set Workspace ...';
            app.setWorkspace.Tag = 'setWorkspace';
            app.GridLayout = uigridlayout(app.figure1);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'M-files', 'Pictures', 'eeg3.jpg');
            app.runApp = uibutton(app.GridLayout, 'state');
            app.runApp.ValueChangedFcn = createCallbackFcn(app, @runApp_Callback, true);
            app.runApp.Tag = 'runApp';
            app.runApp.Text = 'Step 4: Application Module';
            app.runGenSet = uibutton(app.GridLayout, 'state');
            app.runGenSet.ValueChangedFcn = createCallbackFcn(app, @runGenSet_Callback, true);
            app.runGenSet.Tag = 'runGenSet';
            app.runGenSet.Text = 'Step 3: TrainValTest Module';
            app.runPreProcess = uibutton(app.GridLayout, 'state');
            app.runPreProcess.ValueChangedFcn = createCallbackFcn(app, @runPreProcess_Callback, true);
            app.runPreProcess.Tag = 'runPreProcess';
            app.runPreProcess.Text = 'Step 1: PreProcess Module';
            app.runGenFeatures = uibutton(app.GridLayout, 'state');
            app.runGenFeatures.ValueChangedFcn = createCallbackFcn(app, @runGenFeatures_Callback, true);
            app.runGenFeatures.Tag = 'runGenFeatures';
            app.runGenFeatures.Text = 'Step 2: Features Module';
            app.tip_box = uilabel(app.GridLayout);
            app.tip_box.Tag = 'tip_box';
            app.tip_box.Visible = 'off';
            app.tip_box.Text = {'Begin by opening "Tools"'; 'Select "Set workspace..."'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APECSgui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)APECSgui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end