classdef plotSelElect_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        r17         matlab.ui.control.CheckBox
        r27         matlab.ui.control.CheckBox
        r26         matlab.ui.control.CheckBox
        r25         matlab.ui.control.CheckBox
        r35         matlab.ui.control.CheckBox
        r61         matlab.ui.control.CheckBox
        r21         matlab.ui.control.CheckBox
        r29         matlab.ui.control.CheckBox
        r30         matlab.ui.control.CheckBox
        r31         matlab.ui.control.CheckBox
        r39         matlab.ui.control.CheckBox
        selDiag     matlab.ui.control.StateButton
        selRight    matlab.ui.control.StateButton
        selLeft     matlab.ui.control.StateButton
        desDiag     matlab.ui.control.StateButton
        selCan      matlab.ui.control.StateButton
        dselAll     matlab.ui.control.StateButton
        selAll      matlab.ui.control.StateButton
        selOK       matlab.ui.control.StateButton
        Image       matlab.ui.control.Image
    end

    
    properties (Access = private)
        paramElect % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function plotSelElect_OpeningFcn(app, varargin)
            % --- Executes just before plotSelElect is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to plotSelElect (see VARARGIN)
            
            % if exist('paramElect','var')
            %     clear paramElect
            %     global paramElect
            % else
            %##%    global paramElect
            %end
            
            %set(0,'Units','normalized');
            %set(handles.figure1,'Position',[0.3 0.15 0.6 0.7]);
            
            % Choose default command line output for plotSelElect

                        load('tmpSelElect')
            
            app.paramElect.Xlabels = param.Xlabels;
            if isfield(param,'Xlabels2use')
                app.paramElect.Xlabels2use = param.Xlabels2use;
            elseif isfield(app.paramElect,'Xlabels2use') %%% clean up global
                app.paramElect=rmfield(app.paramElect,'Xlabels2use');
                app.paramElect=rmfield(app.paramElect,'indIn2use');
            end
            
            %%% check if old or new lableing
            isOld=sum(strcmp(app.paramElect.Xlabels,'T3')) + sum(strcmp(app.paramElect.Xlabels,'T4')) + ...
                  sum(strcmp(app.paramElect.Xlabels,'T5')) + sum(strcmp(app.paramElect.Xlabels,'T6'));
            if isOld
                app.paramElect.electSet = {'Fp1','Fpz','Fp2', ...
                    'AF3','AF4', ...
                    'F7','F5','F3','F1','Fz','F2','F4','F6','F8' ...
                    'FT7','FC5','FC3','FC1','FCz','FC2','FC4','FC6','FT8' ...
                    'T3','C5','C3','C1','Cz','C2','C4','C6','T4' ...
                    'TP7','CP5','CP3','CP1','CPz','CP2','CP4','CP6','TP8' ...
                    'T5','P5','P3','P1','Pz','P2','P4','P6','T6' ...
                    'PO7','PO5','PO3','PO1','POz','PO2','PO4','PO6','PO8' ...
                    'CB1','O1','Oz','O2','CB2' ...
                    'P9','P10','Iz','AF7','AFz','AF8'};
            else
                app.paramElect.electSet = {'Fp1','Fpz','Fp2', ...
                    'AF3','AF4', ...
                    'F7','F5','F3','F1','Fz','F2','F4','F6','F8' ...
                    'FT7','FC5','FC3','FC1','FCz','FC2','FC4','FC6','FT8' ...
                    'T7','C5','C3','C1','Cz','C2','C4','C6','T8' ...
                    'TP7','CP5','CP3','CP1','CPz','CP2','CP4','CP6','TP8' ...
                    'P7','P5','P3','P1','Pz','P2','P4','P6','P8' ...
                    'PO7','PO5','PO3','PO1','POz','PO2','PO4','PO6','PO8' ...
                    'CB1','O1','Oz','O2','CB2' ...
                    'P9','P10','Iz','AF7','AFz','AF8'};
            end
            
            
             %##%for i=1:length(app.paramElect.Xlabels)
                 %##%j=find(strcmpi(regexprep(app.paramElect.Xlabels{i},'[^\w'']',''),app.paramElect.electSet)==1);
                 %##%if j
                     %##%app.paramElect.indIn(i)=j ;
                 %##%else
                     %##%hW=warndlg(['Electrode ', app.paramElect.Xlabels{i},' not in set'],'Electrode missing');
                     %##%uiwait(hW)
                 %##%end
             %##%end

             for i = 1:length(app.paramElect.Xlabels)
                    label = regexprep(app.paramElect.Xlabels{i}, '[^\w'']', ''); % Odstránenie špeciálnych znakov
               
                    j = find(strcmpi(label, app.paramElect.electSet), 1);
    
                    if ~isempty(j)
                        app.paramElect.indIn(i) = j;
                    else
                        uialert(app.UIFigure, ['Electrode ', app.paramElect.Xlabels{i}, ' not in set'], 'Electrode missing');
                    end
              end
            
             %##%for i=1:length(app.paramElect.Xlabels)
                 %##%strH = ['handles.r',num2str(app.paramElect.indIn(i))];
                 %##%eStr=['set(',strH,',''Visible'',''on'',''Value'',0);'];
                 %##%eval(eStr);
             %##%end
   
             for i=1:length(app.paramElect.Xlabels)
                compName = ['r', num2str(app.paramElect.indIn(i))];  % Napr. "r5"
                disp(compName)
                if isprop(app, compName)  % Overíme, či taký CheckBox existuje
                    cbHandle = app.(compName);
                    cbHandle.Visible = 'on';
                    cbHandle.Value = 0;  % Štandardne nezaškrtnuté
                end
                
              end
            
            
             %##%if isfield(app.paramElect,'Xlabels2use')
                 %##%for i=1:length(app.paramElect.Xlabels2use)
                     %##%j=find(strcmpi(regexprep(app.paramElect.Xlabels2use{i},'[^\w'']',''),app.paramElect.electSet)==1);
                     %##%app.paramElect.indIn2use(i)=j;
                     %##%strH = ['handles.r',num2str(j)];
                    %##% eStr=['set(',strH,',''Value'',1);'];
                     %##%eval(eStr);
                 %##%end
             %##%else
                 %##%for i=1:length(app.paramElect.Xlabels)
                     %##%strH = ['handles.r',num2str(app.paramElect.indIn(i))];
                     %##%eStr=['set(',strH,',''Value'',1);'];
                     %##%eval(eStr);

                 %##%end
             %##%end

             if isfield(app.paramElect, 'Xlabels2use')
                for i=1:length(app.paramElect.Xlabels2use)
                    j = find(strcmpi(regexprep(app.paramElect.Xlabels2use{i}, '[^\w'']', ''), app.paramElect.electSet) == 1);
                    app.paramElect.indIn2use(i) = j;
                    compName = ['r', num2str(j)];
                if isprop(app, compName)
                    app.(compName).Value = 1;  % Zaškrtne checkbox
                end
                end
            else
                for i=1:length(app.paramElect.Xlabels)
                    compName = ['r', num2str(app.paramElect.indIn(i))];
                if isprop(app, compName)
                    app.(compName).Value = 1;  % Zaškrtne checkbox
                end
                end
             end
             drawnow;


            %##%handles.output = hObject;
            
            
            % Update handles structure
            %##% guidata(hObject, handles);
        end

        % Value changed function: desDiag
        function desDiag_Callback(app, event)
            % --- Executes on button press in desDiag.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to desDiag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %##%global paramElect
            
            lenL=length(app.paramElect.indIn);
            newL=ones(1,lenL);
            for k=1:lenL
                tI   = regexpi(app.paramElect.Xlabels{k},'\d');
                if isempty(tI)
                    newL(k)=0;
                end
            end
            indIn= app.paramElect.indIn(newL==0);
            
            for i=1:length(indIn)
                strH = ['r',num2str(indIn(i))];
                app.(strH).Value=0;
            end
            
            %##%set(handles.desDiag,'Value',0);
            app.desDiag.Value=0;
            %##%guidata(hObject, handles);
        end

        % Value changed function: dselAll
        function dselAll_Callback(app, event)
            % --- Executes on button press in dselAll.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dselAll (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of dselAll
            %##%global paramElect
            
            %##%for i=1:length(paramElect.indIn)
                %##%strH = ['handles.r',num2str(paramElect.indIn(i))];
                %##%eStr=['set(',strH,',''Value'',0);'];
                %##%eval(eStr);
            %##%end
            for i=1:length(app.paramElect.indIn)
                strH = ['r',num2str(app.paramElect.indIn(i))];
                app.(strH).Value=0;
            end

            %##%set(handles.dselAll,'Value',0);
            app.dselAll.Value=0;
            %##%guidata(hObject, handles);
        end

        % Value changed function: selAll
        function selAll_Callback(app, event)
            % --- Executes on button press in selAll.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selAll (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %##%global paramElect
            
            %##%for i=1:length(paramElect.indIn)
                %##%strH = ['handles.r',num2str(paramElect.indIn(i))];
                %##%eStr=['set(',strH,',''Value'',1);'];
                %##%eval(eStr);
            %##%end
            for i=1:length(app.paramElect.indIn)
                strH = ['r',num2str(app.paramElect.indIn(i))];
                app.(strH).Value=1;
            end

            %##%set(handles.selAll,'Value',0);
            app.selAll.Value=0;
            %##%guidata(hObject, handles);
        end

        % Value changed function: selCan
        function selCan_Callback(app, event)
            % --- Executes on button press in selCan.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selCan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(app.figure1)
        end

        % Value changed function: selDiag
        function selDiag_Callback(app, event)
            % --- Executes on button press in selDiag.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selDiag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %##%global paramElect
            
            lenL=length(app.paramElect.indIn);
            newL=ones(1,lenL);
            for k=1:lenL
                tI   = regexpi(app.paramElect.Xlabels{k},'\d');
                if ~isempty(tI)
                    newL(k)=0;
                end
            end
            indIn= app.paramElect.indIn(newL==1);
            for i=1:length(indIn)
                strH = ['r',num2str(indIn(i))];
                app.(strH).Value=1;
            end
            
            indIn= app.paramElect.indIn(newL==0);
            for i=1:length(indIn)
                strH = ['r',num2str(indIn(i))];
                app.(strH).Value=0;
            end
            
            %##%set(handles.selDiag,'Value',0);
            app.selDiag.Value=0;
            %##%guidata(hObject, handles);
        end

        % Value changed function: selLeft
        function selLeft_Callback(app, event)
            % --- Executes on button press in selLeft.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selLeft (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %##%global paramElect
            
            lenL=length(app.paramElect.indIn);
            newL=ones(1,lenL);
            for k=1:lenL
                tI   = regexpi(app.paramElect.Xlabels{k},'\d');
                if isempty(tI)
                    newL(k)=0;
                else
                    if ~rem(str2double(app.paramElect.Xlabels{k}(tI(end))),2)
                        newL(k)=0;
                    end
                end
            end
            indIn= app.paramElect.indIn(newL==0);
            
            for i=1:length(app.paramElect.indIn)
                strH = ['r',num2str(app.paramElect.indIn(i))];
                app.(strH).Value=1;
            end
            
            for i=1:length(indIn)
                strH = ['r',num2str(indIn(i))];
                app.(strH).Value=0;
            end
            
            %##%set(handles.selLeft,'Value',0);
            app.selLeft.Value=0;
            %##%guidata(hObject, handles);
        end

        % Value changed function: selOK
        function selOK_Callback(app, event)
            % --- Executes on button press in selOK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selOK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %##%global paramElect
            
            %##%for i=1:length(app.paramElect.indIn)
                %##%strH = ['handles.r',num2str(app.paramElect.indIn(i))];
                %##%eStr=['value(i) = get(',strH,',''Value'');'];
                %##%eval(eStr);
            %##%end

            value = zeros(1, length(app.paramElect.indIn)); % Inicializácia poľa hodnôt

            for i = 1:length(app.paramElect.indIn)
                compName = ['r', num2str(app.paramElect.indIn(i))]; % Napr. "r5"
            if isprop(app, compName) % Overíme, či komponent existuje
                value(i) = app.(compName).Value; % Získame hodnotu checkboxu
            end
            end

            if sum(value) == 0
                hW = warndlg('At least one electrode must be selected !!!','Electrode not selected');
                uiwait(hW);
            else
                app.paramElect.indIn2use   = app.paramElect.indIn(value==1);
                app.paramElect.Xlabels2use = app.paramElect.electSet(app.paramElect.indIn2use);
            
                param.Xlabels = app.paramElect.Xlabels;
            %   param.Xlabels2use = paramElect.Xlabels2use;
            
                if length(app.paramElect.Xlabels)==length(app.paramElect.Xlabels2use)
                    param.Xlabels2use = param.Xlabels;
                    param.var2use = 1:length(param.Xlabels);
                else
                    indL=1;
                    for i=1:length(param.Xlabels)
                        j=find(strcmpi(regexprep(param.Xlabels{i},'[^\w'']',''),app.paramElect.Xlabels2use)==1);
                        if j
                            param.Xlabels2use{indL}=param.Xlabels{i};
                            indL =indL + 1;
                        end
                    end
                    for i=1:length(param.Xlabels2use)
                        param.var2use(i) = find(strcmpi(param.Xlabels2use{i},param.Xlabels)==1);
                    end
                end
                save('tmpSelElect','param');

                close(app.figure1)
            end
        end

        % Value changed function: selRight
        function selRight_Callback(app, event)
            % --- Executes on button press in selRight.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %##%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selRight (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %##%global paramElect
            
            lenL=length(app.paramElect.indIn);
            newL=ones(1,lenL);
            for k=1:lenL
                tI   = regexpi(app.paramElect.Xlabels{k},'\d');
                if isempty(tI)
                    newL(k)=0;
                else
                    if rem(str2double(app.paramElect.Xlabels{k}(tI(end))),2)
                        newL(k)=0;
                    end
                end
            end
            indIn= app.paramElect.indIn(newL==0);
            
            for i=1:length(app.paramElect.indIn)
                strH = ['r',num2str(app.paramElect.indIn(i))];
                app.(strH).Value=1;
            end
            
            for i=1:length(indIn)
                strH = ['r',num2str(indIn(i))];
                app.(strH).Value=0;
            end
            
            %##%set(handles.selRight,'Value',0);
            app.selRight.Value=0;
            %##%guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'plotSelElect';
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'eeg4.jpg';
            app.selOK = uibutton(app.GridLayout, 'state');
            app.selOK.ValueChangedFcn = createCallbackFcn(app, @selOK_Callback, true);
            app.selOK.Tag = 'selOK';
            app.selOK.Text = 'OK-Done!';
            app.selAll = uibutton(app.GridLayout, 'state');
            app.selAll.ValueChangedFcn = createCallbackFcn(app, @selAll_Callback, true);
            app.selAll.Tag = 'selAll';
            app.selAll.Text = 'Select all';
            app.dselAll = uibutton(app.GridLayout, 'state');
            app.dselAll.ValueChangedFcn = createCallbackFcn(app, @dselAll_Callback, true);
            app.dselAll.Tag = 'dselAll';
            app.dselAll.Text = 'Deselect all';
            app.selCan = uibutton(app.GridLayout, 'state');
            app.selCan.ValueChangedFcn = createCallbackFcn(app, @selCan_Callback, true);
            app.selCan.Tag = 'selCan';
            app.selCan.Text = 'Cancel (close)';
            app.desDiag = uibutton(app.GridLayout, 'state');
            app.desDiag.ValueChangedFcn = createCallbackFcn(app, @desDiag_Callback, true);
            app.desDiag.Tag = 'desDiag';
            app.desDiag.Text = 'Deselect Diagonal ';
            app.selLeft = uibutton(app.GridLayout, 'state');
            app.selLeft.ValueChangedFcn = createCallbackFcn(app, @selLeft_Callback, true);
            app.selLeft.Tag = 'selLeft';
            app.selLeft.Text = 'Select left only ';
            app.selRight = uibutton(app.GridLayout, 'state');
            app.selRight.ValueChangedFcn = createCallbackFcn(app, @selRight_Callback, true);
            app.selRight.Tag = 'selRight';
            app.selRight.Text = 'Select right only';
            app.selDiag = uibutton(app.GridLayout, 'state');
            app.selDiag.ValueChangedFcn = createCallbackFcn(app, @selDiag_Callback, true);
            app.selDiag.Tag = 'selDiag';
            app.selDiag.Text = 'Select diagonal only ';
            app.r39 = uicheckbox(app.GridLayout);
            app.r39.Text = 'CP4';
            app.r31 = uicheckbox(app.GridLayout);
            app.r31.Text = 'C6';
            app.r30 = uicheckbox(app.GridLayout);
            app.r30.Text = 'C4';
            app.r29 = uicheckbox(app.GridLayout);
            app.r29.Text = 'C2';
            app.r21 = uicheckbox(app.GridLayout);
            app.r21.Text = 'FC4';
            app.r61 = uicheckbox(app.GridLayout);
            app.r61.Text = 'O1';
            app.r35 = uicheckbox(app.GridLayout);
            app.r35.Text = 'CP3';
            app.r25 = uicheckbox(app.GridLayout);
            app.r25.Text = 'C5';
            app.r26 = uicheckbox(app.GridLayout);
            app.r26.Text = 'C3';
            app.r27 = uicheckbox(app.GridLayout);
            app.r27.Text = 'C1';
            app.r17 = uicheckbox(app.GridLayout);
            app.r17.Text = 'FC3';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotSelElect_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)plotSelElect_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end