classdef gui_2EDF_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        textNumNPLS      matlab.ui.control.Label
        editNumNPLS      matlab.ui.control.EditField
        fileTextE        matlab.ui.control.Label
        textSave         matlab.ui.control.Label
        fileText         matlab.ui.control.Label
        panelFac         matlab.ui.container.Panel
        canTab           matlab.ui.control.Button
        desFac           matlab.ui.control.Button
        allFac           matlab.ui.control.Button
        okTab1           matlab.ui.control.Button
        tabFac           matlab.ui.control.Table
        fileTextF        matlab.ui.control.Label
        pushSave         matlab.ui.control.Button
        sep3             matlab.ui.container.Panel
        pushFeatureData  matlab.ui.control.Button
        sep2             matlab.ui.container.Panel
        pushEpochData    matlab.ui.control.Button
        sep1             matlab.ui.container.Panel
        loadFile         matlab.ui.control.Button
    end

    
    methods (Access = private)
        function handles = printfileText(app, handles, param)
            strFT(1)={'Results'};
            strFT(2)={'------------'};
            strFT(3)={['file name: ' param.fileName]};
            strFT(4)={['method used: ' param.Method]};
            if isfield(param,'kernelFcn')
                strFT(end+1)={['kernel function: ' param.kernelFcn]};
            end
            if isfield(param,'numFactors')
                strFT(end+1)={['total number of factors: ' num2str(param.numFactors)]};
            end
            if isfield(param,'numNPLS2use')
                strFT(end+1)={['factors to use (up to): ' num2str(param.numNPLS2use)]};
            end
            strP='factors to use: ';
            if isfield(param,'fac2use')
                for i=1:length(param.fac2use)
                    strP = [strP,num2str(param.fac2use(i)),', '];
                end
                strFT{end+1}=strP(1:end-2);
            elseif ~isfield(param,'numNPLS2use')
                strFT(end+1)={[strP,'all']};
            end
            if isfield(param,'varLabels')
                ppStr=param.varLabels;
                pStr='used variables: ';
                for sI=1:length(ppStr)
                    pStr=[pStr,ppStr{sI},','];
                end
                strFT{end+1}=pStr(1:end-1);
            end
            
            set(handles.fileText,'Visible','on','String',strFT);
        end
        
        function handles = printfileTextE(app, handles, param)
            strFT(1)={'Data file'};
            strFT(2)={'------------'};
            
            strP=['file name: ' param.fileNameE{1}];
            for f=2:length(param.fileNameE)
                strP =[strP,', ' param.fileNameE{f}];
            end
            strFT{3}=strP;
            
            strFT(4)={['sample freq.: ' num2str(param.sampleFreq) param.sampleFreqUnit]};
            
            
            set(handles.fileTextE,'Visible','on','String',strFT);
        end
        
        function handles = printfileTextF(app, handles, param)
            strFT(1)={'Feature file'};
            strFT(2)={'------------'};
            strFT(3)={['file name: ' param.fileNameF]};
            strFT(4)={['features type: ' param.featureType]};
            
            set(handles.fileTextF,'Visible','on','String',strFT);
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('gui_2EDF_App.mat');
            
            % Set component properties that require runtime configuration
            app.tabFac.ColumnFormat = {[] 'logical'};
            app.tabFac.Data = componentData.tabFac.Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gui_2EDF_OpeningFcn(app, varargin)
            % --- Executes just before gui_2EDF is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to gui_2EDF (see VARARGIN)
            clear global
            global paramEDF
            
            % Choose default command line output for gui_2EDF
            handles.output = hObject;
            
            
            set(0,'Units','normalized');
            set(handles.figure1,'Position',[0.4 0.5 0.4 0.4]);
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: allFac
        function allFac_Callback(app, event)
            % --- Executes on button press in allFac.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to allFac (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramEDF
            
            numVar = paramEDF.numFactors;
            for i=1:numVar
                tabLab{i}=num2str(i);
                tabValues(i) = true;
            end
            set(handles.tabFac,'parent',handles.panelFac,'Data',tabValues, ...
                'RowName',{'on/off'},'ColumnFormat', {'logical'}, ...
                'ColumnName',tabLab, ...
                'ColumnEditable',true(1,numVar),'Visible','on');
            
            guidata(hObject,handles)
        end

        % Button pushed function: canTab
        function canTab_Callback(app, event)
            % --- Executes on button press in canTab.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to canTab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramEDF
            
            set(handles.panelFac,'Visible','off')
            
            handles = printfileText(app, handles,paramEDF);
            
            set(handles.sep1,'Visible','on')
            set(handles.pushEpochData,'Visible','on')
            guidata(hObject,handles)
        end

        % Button pushed function: desFac
        function desFac_Callback(app, event)
            % --- Executes on button press in desFac.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to desFac (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramEDF
            
            numVar = paramEDF.numFactors;
            for i=1:numVar
                tabLab{i}=num2str(i);
                tabValues(i) = false;
            end
            set(handles.tabFac,'parent',handles.panelFac,'Data',tabValues, ...
                'RowName',{'on/off'},'ColumnFormat', {'logical'}, ...
                'ColumnName',tabLab, ...
                'ColumnEditable',true(1,numVar),'Visible','on');
            
            guidata(hObject,handles)
        end

        % Value changed function: editNumNPLS
        function editNumNPLS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editNumNPLS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editNumNPLS as text
            %        str2double(get(hObject,'String')) returns contents of editNumNPLS as a double
            global paramEDF
            
            nsf=str2double(get(handles.editNumNPLS,'String'));
            if isfloat(nsf) && nsf > 0 && nsf <= paramEDF.numFactors
                paramEDF.numNPLS2use = nsf;
                set(handles.editNumNPLS,'Visible','off','String',[]);
                set(handles.textNumNPLS,'Visible','off','String',[]);
                set(handles.sep1,'Visible','on')
                set(handles.pushEpochData,'Visible','on');
                handles = printfileText(app, handles,paramEDF);
            end
            
            
            guidata(hObject,handles)
        end

        % Button pushed function: loadFile
        function loadFile_Callback(app, event)
            % --- Executes on button press in loadFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to loadFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramEDF
            
            set(handles.loadFile,'Enable','off');
            
            [fileName, pathName] = uigetfile ({'./Results/*.mat'},'Open File');
            pathName=getRelPath(pathName);
            
            if fileName
                %%% load header file
                strF=strcat(pathName,fileName);
                warning off
                load(strF,'param')
                warning on
                paramEDF.fileName    = fileName;
                paramEDF.pathName    = pathName;
                paramEDF.Method      = param.genMethod;
                paramEDF.varUsed     = param.var2use;
                paramEDF.freqIn2use  = param.freqIn2use;
                if isfield(param,'Xlabels2use')
                    paramEDF.varLabels = param.Xlabels2use;
                end
                if isfield(param,'numFactors')
                    if isfield(param,'reRunPARAFAC')
                        paramEDF.numFactors = param.reRunPARAFAC.numFactors;
                    else
                        paramEDF.numFactors = param.numFactors;
                    end
                end
                if strcmp(paramEDF.Method,'KPLS')
                    paramEDF.kernelFcn=param.kernelFcn;
                end
                set(handles.loadFile,'Visible','off')
                handles = printfileText(app, handles,paramEDF);
            
                switch paramEDF.Method
                    case {'PARAFAC'}
                        set(handles.panelFac,'Visible','on')
                        %%% do table here
                        numVar = paramEDF.numFactors;
                        for i=1:numVar
                            tabLab{i}=num2str(i);
                            tabValues(i) = true;
                        end
                        set(handles.okTab1,'Visible','on')
                        set(handles.allFac,'Visible','on')
                        set(handles.desFac,'Visible','on')
                        set(handles.canTab,'Visible','on')
                        set(handles.tabFac,'parent',handles.panelFac,'Data',tabValues, ...
                            'RowName',{'on/off'},'ColumnFormat', {'logical'}, ...
                            'ColumnName',tabLab, ...
                            'ColumnEditable',true(1,numVar),'Visible','on');
                    case {'NPLS'}
                        set(handles.editNumNPLS,'Visible','on','String',[])
                        set(handles.textNumNPLS,'Visible','on',...
                             'String',['Define order of NPLS. (max. order ',num2str(paramEDF.numFactors),')'])
                end
            else
               set(handles.loadFile,'Enable','on')
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: okTab1
        function okTab1_Callback(app, event)
            % --- Executes on button press in okTab1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to okTab1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramEDF
            
            set(handles.panelFac,'Visible','off')
            
            
            datV = get(handles.tabFac,'Data');
            fac  = [1:paramEDF.numFactors];
            fac  = fac(datV);
            if length(fac) ~= paramEDF.numFactors;
                paramEDF.fac2use = fac;
            end
            
            handles = printfileText(app, handles,paramEDF);
            
            set(handles.sep1,'Visible','on')
            set(handles.pushEpochData,'Visible','on')
            guidata(hObject,handles)
        end

        % Button pushed function: pushEpochData
        function pushEpochData_Callback(app, event)
            % --- Executes on button press in pushEpochData.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushEpochData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramEDF
            
            set(handles.pushEpochData,'Enable','off');
            
            [fileNameE, pathNameE] = uigetfile ({'./Data/EpochedData/*.mat'},'Open File','MultiSelect','on');
            pathNameE=getRelPath(pathNameE);
            
            if ~iscell(fileNameE)
                if fileNameE
                    fileNameE=cellstr(fileNameE);
                end
            end
            
            if iscell(fileNameE)
                %%% load header file
                strF=strcat(pathNameE,fileNameE{1});
                warning off
                load(strF,'header')
                warning on
                for f=1:length(fileNameE)
                    paramEDF.fileNameE{f}  = fileNameE{f};
                end
                paramEDF.pathNameE       = pathNameE;
                paramEDF.sampleFreq      = header.sampleFreq;
                paramEDF.sampleFreqUnit  = header.sampleFreqUnit;
                set(handles.pushEpochData,'Visible','off')
                set(handles.sep2,'Visible','on')
                handles = printfileTextE(app, handles,paramEDF);
                set(handles.pushFeatureData,'Visible','on')
            else
                set(handles.pushEpochData,'Enable','on')
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: pushFeatureData
        function pushFeatureData_Callback(app, event)
            % --- Executes on button press in pushFeatureData.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushFeatureData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramEDF
            
            set(handles.pushFeatureData,'Enable','off');
            
            [fileNameF, pathNameF] = uigetfile ({'./Data/FeaturesData/*.mat'},'Open File');
            pathNameF=getRelPath(pathNameF);
            
            if fileNameF
                %%% load header file
                strF=strcat(pathNameF,fileNameF);
                warning off
                load(strF,'header')
                warning on
                paramEDF.fileNameF       = fileNameF;
                paramEDF.pathNameF       = pathNameF;
                paramEDF.featureType     = header.featureType;
                paramEDF.psdMethod       = header.psdMethod;
            
                set(handles.pushFeatureData,'Visible','off')
                set(handles.sep3,'Visible','on')
                handles = printfileTextF(app, handles,paramEDF);
                set(handles.pushSave,'Visible','on')
            else
               set(handles.pushFeatureData,'Enable','on')
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: pushSave
        function pushSave_Callback(app, event)
            % --- Executes on button press in pushSave.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushSave (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramEDF
            
            switchS = false;
            if length(paramEDF.fileNameE)==1
                [paramEDF.fNameS, paramEDF.pathNameS] = uiputfile( {'*.edf','EDF File'}, ...
                    'Define name for EDF file',['./EDF/',paramEDF.fileNameE{1}(1:end-4),'.edf']);
                paramEDF.pathNameS=getRelPath(paramEDF.pathNameS);
                if paramEDF.fNameS
                    switchS = true;
                    paramEDF.fNameS=cellstr(paramEDF.fNameS);
                end
            else
                paramEDF.fNameS=paramEDF.fileNameE;
                paramEDF.pathNameS='./EDF/';
                switchS = true;
            end
            
            if switchS % paramEDF.fNameS
                if ~paramEDF.pathNameS
                    paramEDF.pathNameS='./EDF/';
                end
                res2edf(paramEDF);
                set(handles.pushSave,'Visible','off')
                stP=['File was saved to ',paramEDF.pathNameS,paramEDF.fNameS{1}];
                for f=2:length(paramEDF.fNameS)
                    stP=[stP,', ',paramEDF.fNameS{f}];
                end
                set(handles.textSave,'Visible','on','String',cellstr(stP));
                hM=msgbox('Great !!! File(s) were generated and saved.','File saved');
                uiwait(hM);
                close(handles.figure1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'gui_2EDF';
            app.figure1.Tag = 'figure1';
            app.loadFile = uibutton(app.figure1, 'push');
            app.loadFile.ButtonPushedFcn = createCallbackFcn(app, @loadFile_Callback, true);
            app.loadFile.Tag = 'loadFile';
            app.loadFile.Text = 'Load Results ';
            app.sep1 = uipanel(app.figure1);
            app.sep1.Visible = 'off';
            app.sep1.Tag = 'sep1';
            app.pushEpochData = uibutton(app.figure1, 'push');
            app.pushEpochData.ButtonPushedFcn = createCallbackFcn(app, @pushEpochData_Callback, true);
            app.pushEpochData.Tag = 'pushEpochData';
            app.pushEpochData.Visible = 'off';
            app.pushEpochData.Text = 'Load data file (epoched data) ';
            app.sep2 = uipanel(app.figure1);
            app.sep2.Visible = 'off';
            app.sep2.Tag = 'sep2';
            app.pushFeatureData = uibutton(app.figure1, 'push');
            app.pushFeatureData.ButtonPushedFcn = createCallbackFcn(app, @pushFeatureData_Callback, true);
            app.pushFeatureData.Tag = 'pushFeatureData';
            app.pushFeatureData.Visible = 'off';
            app.pushFeatureData.Text = 'Select Feature Data';
            app.sep3 = uipanel(app.figure1);
            app.sep3.Visible = 'off';
            app.sep3.Tag = 'sep3';
            app.pushSave = uibutton(app.figure1, 'push');
            app.pushSave.ButtonPushedFcn = createCallbackFcn(app, @pushSave_Callback, true);
            app.pushSave.Tag = 'pushSave';
            app.pushSave.Visible = 'off';
            app.pushSave.Text = 'Save to EDF ';
            app.fileTextF = uilabel(app.figure1);
            app.fileTextF.Tag = 'fileTextF';
            app.fileTextF.Visible = 'off';
            app.fileTextF.Text = 'Static Text';
            app.panelFac = uipanel(app.figure1);
            app.panelFac.Title = 'Select factors to use: ';
            app.panelFac.Visible = 'off';
            app.panelFac.Tag = 'panelFac';
            app.tabFac = uitable(app.panelFac);
            app.tabFac.ColumnEditable = [false false];
            app.tabFac.Visible = 'off';
            app.tabFac.Tag = 'tabFac';
            app.okTab1 = uibutton(app.panelFac, 'push');
            app.okTab1.ButtonPushedFcn = createCallbackFcn(app, @okTab1_Callback, true);
            app.okTab1.Tag = 'okTab1';
            app.okTab1.Visible = 'off';
            app.okTab1.Text = 'OK';
            app.allFac = uibutton(app.panelFac, 'push');
            app.allFac.ButtonPushedFcn = createCallbackFcn(app, @allFac_Callback, true);
            app.allFac.Tag = 'allFac';
            app.allFac.Visible = 'off';
            app.allFac.Text = 'Sellect all';
            app.desFac = uibutton(app.panelFac, 'push');
            app.desFac.ButtonPushedFcn = createCallbackFcn(app, @desFac_Callback, true);
            app.desFac.Tag = 'desFac';
            app.desFac.Visible = 'off';
            app.desFac.Text = 'Deselect all ';
            app.canTab = uibutton(app.panelFac, 'push');
            app.canTab.ButtonPushedFcn = createCallbackFcn(app, @canTab_Callback, true);
            app.canTab.Tag = 'canTab';
            app.canTab.Visible = 'off';
            app.canTab.Text = 'Cancel';
            app.fileText = uilabel(app.figure1);
            app.fileText.Tag = 'fileText';
            app.fileText.Visible = 'off';
            app.fileText.Text = 'Static Text';
            app.textSave = uilabel(app.figure1);
            app.textSave.Tag = 'textSave';
            app.textSave.Visible = 'off';
            app.textSave.Text = 'Static Text';
            app.fileTextE = uilabel(app.figure1);
            app.fileTextE.Tag = 'fileTextE';
            app.fileTextE.Visible = 'off';
            app.fileTextE.Text = 'Static Text';
            app.editNumNPLS = uieditfield(app.figure1, 'text');
            app.editNumNPLS.ValueChangedFcn = createCallbackFcn(app, @editNumNPLS_Callback, true);
            app.editNumNPLS.Tag = 'editNumNPLS';
            app.editNumNPLS.Visible = 'off';
            app.textNumNPLS = uilabel(app.figure1);
            app.textNumNPLS.Tag = 'textNumNPLS';
            app.textNumNPLS.Visible = 'off';
            app.textNumNPLS.Text = 'Define order of NPLS';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_2EDF_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)gui_2EDF_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end