classdef gui_BCI2000_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        textBCI2000  matlab.ui.control.Label
        pushOK1      matlab.ui.control.Button
        textFacSave  matlab.ui.control.Label
        setFacSave   matlab.ui.control.EditField
        pushOK       matlab.ui.control.Button
        fileText     matlab.ui.control.Label
        topPanel     matlab.ui.container.Panel
        loadFile     matlab.ui.control.Button
    end

    
    methods (Access = private)
        function handles = printfileText(app, handles, param)
            %strFT(1)={'Results of: '};
            strFT(1)={['file name: ' param.fileName]};
            strFT(2)={['method used: ' param.Method]};
            if isfield(param,'kernelFcn')
                strFT(end+1)={['kernel function: ' param.kernelFcn]};
            end
            if isfield(param,'numFactors')
                strFT(end+1)={['total number of factors: ' num2str(param.numFactors)]};
            end
            if isfield(param,'varLabels')
                ppStr=param.varLabels;
                pStr='used variables: ';
                for sI=1:length(ppStr)
                    pStr=[pStr,ppStr{sI},','];
                end
                strFT{end+1}=pStr(1:end-1);
            end
            
            set(handles.fileText,'Visible','on','String',strFT);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gui_BCI2000_OpeningFcn(app, varargin)
            % --- Executes just before gui_BCI2000 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to gui_BCI2000 (see VARARGIN)
            clear global
            global paramBCI
            
            % Choose default command line output for gui_BCI2000
            handles.output = hObject;
            
            set(0,'Units','normalized');
            set(handles.figure1,'Position',[0.4 0.5 0.4 0.4]);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: loadFile
        function loadFile_Callback(app, event)
            % --- Executes on button press in loadFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to loadFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramBCI
            
            set(handles.loadFile,'Enable','off');
            
            [fileName, pathName] = uigetfile ({'./Results/*.mat'},'Open File');
            pathName=getRelPath(pathName);
            
            if fileName
                %%% load header file
                strF=strcat(pathName,fileName);
                warning off
                load(strF)
                warning on
                if strcmp(param.genMethod,'KPLS') && strcmp(param.kernelFcn,'Linear')
                    Method='linPLS';
                else
                    Method=param.genMethod;
                end
                switch Method
                    case{'NPLS','linPLS'}
                        if ~exist('res','var')
                            hE=errordlg('''res'' structure not present in the file ...','Error - header missing');
                            uiwait(hE);
                            set(handles.loadFile,'Enable','on');
                        else
                            paramBCI             = res;
                            %%% for controling saving
                            paramBCI.saveOKbci = false;
                            paramBCI.saveOKxls = false;
            
                            paramBCI.fileName    = fileName;
                            paramBCI.pathName    = pathName;
                            paramBCI.Method      = param.genMethod;
                            paramBCI.featureAll  = param.fLines;
                            paramBCI.featureIn   = param.fLines(param.fLinesInd2use);
                            paramBCI.varUsed     = param.var2use;
                            if isfield(param,'Xlabels2use')
                                paramBCI.varLabels = param.Xlabels2use;
                            end
                            if isfield(param,'numFactors')
                                paramBCI.numFactors = param.numFactors;
                            end
                            if strcmp(paramBCI.Method,'KPLS')
                                paramBCI.kernelFcn=param.kernelFcn;
                            end
            
                            handles = printfileText(app, handles,paramBCI);
                            set(handles.loadFile,'Visible','off')
                            set(handles.pushOK1,'Visible','on')
                            set(handles.radBCI2000,'Visible','on','Value',1)
                            set(handles.radXLS,'Visible','on','Value',1)
                            set(handles.topPanel,'Visible','on')
                        end
                    otherwise
                        if strcmp(Method,'KPLS')
                            hE=errordlg(['Error !!! ... nonlinear ', Method, ' method was selected. ' ...
                                'Only weights for linear KPLS can be saved.'],'Wrong method');
                        else
                            hE=errordlg(['Error !!! ... ', Method, ' method was selected. ' ...
                                'Weights for this model can''t be saved.'],'Wrong method');
                        end
                        uiwait(hE);
                        set(handles.loadFile,'Enable','on')
                end
            else
               set(handles.loadFile,'Enable','on')
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: pushOK1
        function pushOK1_Callback(app, event)
            % --- Executes on button press in pushOK1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushOK1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramBCI
            
            if get(handles.radBCI2000,'Value') || get(handles.radXLS,'Value')
                set(handles.pushOK1,'Visible','off')
                set(handles.radBCI2000,'Visible','off')
                set(handles.radXLS,'Visible','off')
            
                set(handles.pushOK,'Visible','on')
                set(handles.topPanel,'Visible','on')
                set(handles.textFacSave,'Visible','on')
                set(handles.setFacSave,'Visible','on','String',num2str(paramBCI.numFactors))
                if get(handles.radBCI2000,'Value')
                    set(handles.textBCI2000,'Visible','on');
                    set(handles.radLab,'Visible','on','Value',0);
                    set(handles.radBin,'Visible','on','Value',1);
                end
            
            else
                hE=errordlg('None saving weights file selected .... nothing to do!!!')
                uiwait(hE)
            end
            guidata(hObject,handles)
        end

        % Button pushed function: pushOK
        function pushOK_Callback(app, event)
            % --- Executes on button press in pushOK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushOK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramBCI
            
            set(handles.pushOK,'Enable','off');
            set(handles.setFacSave,'Enable','off');
            set(handles.radLab,'Enable','off');
            set(handles.radBin,'Enable','off');
            
            paramBCI.numFac2Save=str2double(get(handles.setFacSave,'String'));
            if paramBCI.numFac2Save > size(paramBCI.Xfactors{1},2)
                hE=errordlg(['Error: Model with max. number of ', num2str(size(paramBCI.Xfactors{1},2)), ...
                             ' factors can be extracted']);
                uiwait(hE)
                set(handles.setFacSave,'Enable','on','String',num2str(size(paramBCI.Xfactors{1},2)));
                set(handles.pushOK,'Enable','on','Value',0);
                set(handles.radLab,'Enable','on');
                set(handles.radBin,'Enable','on');
                guidata(hObject,handles)
            else
                set(handles.setFacSave,'Enable','off');
                set(handles.pushOK,'Enable','off','Value',0);
                set(handles.radLab,'Enable','off');
                set(handles.radBin,'Enable','off');
            
                if ~get(handles.radBCI2000,'Value')
                    paramBCI.saveOKbci = true;
                end
                if ~get(handles.radXLS,'Value')
                    paramBCI.saveOKxls = true;
                end
            
                if get(handles.radBCI2000,'Value') && (paramBCI.saveOKbci == false)
                    [fName, pathName] = uiputfile( {'*.txt','Text File'}, ...
                        'Save BCI2000 weights parameters as ...',['./Weights_BCI2000_xls/BCI2000_weights_', ...
                        paramBCI.fileName(1:end-4),'_',num2str(paramBCI.numFac2Save),'factorsModel.txt']);
            
                    if pathName
                        pathName=getRelPath(pathName);
            
                        if get(handles.radBin,'Value')
                            typeOut = 'bin';
                        end
                        if get(handles.radLab,'Value')
                            typeOut = 'labelHz';
                        end
            
                        fH = getWeights4BCI2000([pathName,fName],typeOut,paramBCI);
                        if ~fH
                            paramBCI.saveOKbci = true;
                        else
                            paramBCI.saveOKbci = false;
                        end
                    else
                        paramBCI.saveOKbci = false;
                    end
                end
            
                if get(handles.radXLS,'Value') && (paramBCI.saveOKxls == false)
                    [fName, pathName] = uiputfile( {'*.xls','Excel File'}, ...
                        'Save weights parameters as excel file...',['./Weights_BCI2000_xls/weights_', ...
                        paramBCI.fileName(1:end-4),'_',num2str(paramBCI.numFac2Save),'factorsModel.xls']);
            
                    if pathName
                        pathName=getRelPath(pathName);
            
                        fH = saveWeights2xls([pathName,fName],paramBCI);
                        if ~fH
                            paramBCI.saveOKxls = true;
                        else
                            paramBCI.saveOKxls = false;
                        end
                    else
                        paramBCI.saveOKbci = false;
                    end
                end
            
            
                if (paramBCI.saveOKbci == true)  && (paramBCI.saveOKbci == true)
                    close(handles.figure1);
                else
                    set(handles.pushOK,'Enable','on');
                    set(handles.setFacSave,'Enable','on');
                    set(handles.radLab,'Enable','on');
                    set(handles.radBin,'Enable','on');
                    guidata(hObject,handles)
                end
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'gui_SaveWeights';
            app.figure1.Tag = 'figure1';
            app.loadFile = uibutton(app.figure1, 'push');
            app.loadFile.ButtonPushedFcn = createCallbackFcn(app, @loadFile_Callback, true);
            app.loadFile.Tag = 'loadFile';
            app.loadFile.Text = 'Load Results File';
            app.topPanel = uipanel(app.figure1);
            app.topPanel.Visible = 'off';
            app.topPanel.Tag = 'topPanel';
            app.fileText = uilabel(app.figure1);
            app.fileText.Tag = 'fileText';
            app.fileText.Visible = 'off';
            app.fileText.Text = '';
            app.pushOK = uibutton(app.figure1, 'push');
            app.pushOK.ButtonPushedFcn = createCallbackFcn(app, @pushOK_Callback, true);
            app.pushOK.Tag = 'pushOK';
            app.pushOK.Visible = 'off';
            app.pushOK.Text = 'OK - Save weights ! ';
            app.setFacSave = uieditfield(app.figure1, 'text');
            app.setFacSave.Tag = 'setFacSave';
            app.setFacSave.Visible = 'off';
            app.setFacSave.Value = '2';
            app.textFacSave = uilabel(app.figure1);
            app.textFacSave.Tag = 'textFacSave';
            app.textFacSave.Visible = 'off';
            app.textFacSave.Text = 'Model of how many factors to save? ';
            app.pushOK1 = uibutton(app.figure1, 'push');
            app.pushOK1.ButtonPushedFcn = createCallbackFcn(app, @pushOK1_Callback, true);
            app.pushOK1.Tag = 'pushOK1';
            app.pushOK1.Visible = 'off';
            app.pushOK1.Text = 'OK';
            app.textBCI2000 = uilabel(app.figure1);
            app.textBCI2000.Tag = 'textBCI2000';
            app.textBCI2000.Visible = 'off';
            app.textBCI2000.Text = 'Select BCI2000 format  ';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_BCI2000_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)gui_BCI2000_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end