classdef gui_genFeatures_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        butDefFreq           matlab.ui.control.Button
        textProcWTC          matlab.ui.control.Label
        procWTcoef           matlab.ui.control.ListBox
        SubWaveType          matlab.ui.control.ListBox
        textScales           matlab.ui.control.Label
        ScaleTab             matlab.ui.control.EditField
        WaveType             matlab.ui.control.ListBox
        pushFinish           matlab.ui.control.Button
        pushFinishSelection  matlab.ui.control.Button
        psdSpecType          matlab.ui.control.ListBox
        textSpecType         matlab.ui.control.Label
        tableParam           matlab.ui.control.Table
        checkZeroMean        matlab.ui.control.CheckBox
        pushGoBack           matlab.ui.control.Button
        pushSaveParam        matlab.ui.control.Button
        pushGenFeatures      matlab.ui.control.Button
        repFile              matlab.ui.control.Button
        delFile              matlab.ui.control.Button
        corrFile             matlab.ui.control.Button
        pushOK2              matlab.ui.control.Button
        textHz               matlab.ui.control.Label
        rangeHz              matlab.ui.control.EditField
        textFile             matlab.ui.control.Label
        pushOK_1             matlab.ui.control.Button
        textParam            matlab.ui.control.Label
        WinType              matlab.ui.control.ListBox
        textWinType          matlab.ui.control.Label
        selMethod            matlab.ui.control.ListBox
        textMethod           matlab.ui.control.Label
        loadParam            matlab.ui.control.Button
        loadFile             matlab.ui.control.Button
    end

    
    methods (Access = private)
        function handles = printParameters(app, param, handles)
            
            % if nargin < 3
            %     resL=false
            % end
            % if ~resL
            if param.fileIndx > 0
                if isfield(param,'paramSaveFile')
                    strLB={['Parameters Summary (save to: ',param.paramSaveFile,'):']};
                else
                    strLB={'Parameters Summary (save to: ???):'};
                end
                for i=1:param.fileIndx
                    strLB=[strLB; [int2str(i),')'] ;param.fileName{i}];
                    if isfield(param.fileHeader{i},'sampleFreq');
                        pStr= ['sampling freq: ',int2str(param.fileHeader{i}.sampleFreq), ...
                            ' [',param.fileHeader{i}.sampleFreqUnit,']'];
                        strLB=[strLB;pStr];
                    end
                    pStr=['epoch length: ',num2str(param.fileHeader{i}.lenEpoch), ...
                        ' [',param.fileHeader{i}.lenEpochUnit,']'];
                    strLB=[strLB;pStr];
                    pStr=['overlap: ',num2str(param.fileHeader{i}.lenOverlap), ...
                        ' [',param.fileHeader{i}.lenOverlapUnit,']'];
                    strLB=[strLB;pStr];
                    if isfield(param.fileHeader{i},'featureType')
                        switch param.fileHeader{i}.featureType
                            case 'psd'
                                if isfield(param.fileHeader{i},'psdMethod');
                                    strLB=[strLB; ['psd method: ',param.fileHeader{i}.psdMethod]];
                                    strLB=[strLB; ['psd window type: ',param.fileHeader{i}.psdWindowType]];
                                    switch param.fileHeader{i}.psdMethod
                                        case 'fft'
                                            if isfield(param.fileHeader{i},'nSubset');
                                                strLB=[strLB; ['number of subsets: ',num2str(param.fileHeader{i}.nSubset)]];
                                            end
                                            if isfield(param.fileHeader{i},'detrendL');
                                                strLB=[strLB; ['linear detrend: ',(param.fileHeader{i}.detrendL)]];
                                            end
                                            if isfield(param.fileHeader{i},'fftNorm2Hz');
                                                strLB=[strLB; ['normalize to uV(2)/Hz: ',(param.fileHeader{i}.fftNorm2Hz)]];
                                            end
                                            % strLB=[strLB; ['number of FFT points: ',num2str(param.fileHeader{i}.nFFT)]];
                                        case 'welch'
                                            strLB=[strLB; ['window length: ',num2str(param.fileHeader{i}.psdWin_pwelch)]];
                                            strLB=[strLB; ['overlap: ',num2str(param.fileHeader{i}.psdWinOverlap_pwelch)]];
                                            strLB=[strLB; ['number of FFT points: ',num2str(param.fileHeader{i}.nFFT)]];
                                            if isfield(param.fileHeader{i},'detrendL');
                                                strLB=[strLB; ['linear detrend: ',(param.fileHeader{i}.detrendL)]];
                                            end
                                        case 'thomson'
                                            strLB=[strLB; ['time-bandwidth product: ',num2str(param.fileHeader{i}.nw_pmtm)]];
                                            strLB=[strLB; ['number of FFT points: ',num2str(param.fileHeader{i}.nFFT)]];
                                            if isfield(param.fileHeader{i},'detrendL');
                                                strLB=[strLB; ['linear detrend: ',(param.fileHeader{i}.detrendL)]];
                                            end
                                    end
                                    strLB=[strLB;['type of spectra: ',param.fileHeader{i}.psdSpectraType]];
                                    if isfield(param.fileHeader{i},'freqIn');
                                        ss=[];
                                        for ii=1:length(param.fileHeader{i}.freqIn)
                                            ss= [ss,' [', num2str(param.fileHeader{i}.freqIn{ii}(1)),',', ...
                                                num2str(param.fileHeader{i}.freqIn{ii}(2)),'] ;'];
                                        end
                                        ss=ss(1:end-1);
                                        strLB=[strLB; ['freq. band(s) to compute: ',ss]];
                                    end
                                end
                            case 'irasa'
                                if isfield(param.fileHeader{i},'irasaWindowType');
                                    strLB=[strLB; ['psd window type: ',param.fileHeader{i}.irasaWindowType]];
                                end
                                if isfield(param.fileHeader{i},'nSubset');
                                    strLB=[strLB; ['number of subsets: ',num2str(param.fileHeader{i}.nSubset)]];
                                end
                                if isfield(param.fileHeader{i},'hset')
                                    strLB=[strLB; ['hset: ',(param.fileHeader{i}.hset)]];
                                end
                                if isfield(param.fileHeader{i},'detrendL');
                                    strLB=[strLB; ['linear detrend: ',(param.fileHeader{i}.detrendL)]];
                                end
                                if isfield(param.fileHeader{i},'rectOsc');
                                    strLB=[strLB; ['rectify osc. part: ',param.fileHeader{i}.rectOsc]];
                                end
                                if isfield(param.fileHeader{i},'fftNorm2Hz');
                                    strLB=[strLB; ['normalize to uV(2)/Hz: ',param.fileHeader{i}.fftNorm2Hz]];
                                end
                                if isfield(param.fileHeader{i},'irasaSpectraType');
                                    strLB=[strLB;['type of spectra: ',param.fileHeader{i}.irasaSpectraType]];
                                end
                            case 'coherence'
                                if isfield(param.fileHeader{i},'coherMethod');
                                    strLB=[strLB; ['coherence method: ',param.fileHeader{i}.coherMethod]];
                                    strLB=[strLB; ['coherence window type: ',param.fileHeader{i}.coherWindowType]];
                                    strLB=[strLB; ['window length: ',num2str(param.fileHeader{i}.coherWin)]];
                                    strLB=[strLB; ['overlap: ',num2str(param.fileHeader{i}.coherWinOverlap)]];
                                    strLB=[strLB; ['number of FFT points: ',num2str(param.fileHeader{i}.nFFT)]];
                                    if isfield(param.fileHeader{i},'freqIn');
                                        ss=[];
                                        for ii=1:length(param.fileHeader{i}.freqIn)
                                            ss= [ss,' [', num2str(param.fileHeader{i}.freqIn{ii}(1)),',', ...
                                                num2str(param.fileHeader{i}.freqIn{ii}(2)),'] ;'];
                                        end
                                        ss=ss(1:end-1);
                                        strLB=[strLB; ['freq. band(s) to compute: ',ss]];
                                    end
                                end
                            case 'cwt'
                                if isfield(param.fileHeader{i},'cwtType');
                                    strLB=[strLB; ['method: cwt']];
                                    strLB=[strLB; ['cwt type: ',param.fileHeader{i}.cwtType]];
                                    switch param.fileHeader{i}.cwtUnit
                                        case 'scales'
                                            strLB=[strLB; ['cwt scales: ',get(handles.ScaleTab,'String')]];
                                        case 'freqs'
                                            strLB=[strLB; ['cwt freqs.: ',get(handles.ScaleTab,'String')]];
                                    end
                                    strLB=[strLB; ['proc wt coeff: ',param.fileHeader{i}.procWTcoef]];
                                end
                        end
                    end
                    %%% zero mean
                    if isfield(param.fileHeader{i},'zeroMean');
                        if param.fileHeader{i}.zeroMean
                            pStr='zero mean: yes';
                        else
                            pStr='zero mean: no';
                        end
                        strLB=[strLB;pStr];
                    end
                end
                %    set(listB,'String',strLB)
            end
            if ~isfield(handles,'listboxSum')
                pozF=get(handles.figure1,'Position');
                handles.listboxSum=uicontrol('Parent',handles.figure1,'Style','listbox', ...
                    'Fontweight','bold','Units','normalized', ...
                    'Position',[0.59 0.01 0.4 0.98], ...
                    'Selected','off','SelectionHighlight','off');
                %%%%%%
            end
            set(handles.listboxSum,'Value',1);
            set(handles.listboxSum,'String',strLB)
            set(handles.listboxSum,'Visible','on');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('gui_genFeatures_App.mat');
            
            % Set component properties that require runtime configuration
            app.pushSaveParam.UserData = componentData.pushSaveParam.UserData;
            app.tableParam.BackgroundColor = [0.8 0.8 0.8];
            app.tableParam.ColumnFormat = {[] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gui_genFeatures_OpeningFcn(app, varargin)
            % --- Executes just before gui_genFeatures is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to gui_genFeatures (see VARARGIN)
            
            clear global
            %global paramGenFeatures
            
            set(0,'Units','normalized');
            set(handles.figure1,'Position',[0.25 0.25 0.6 0.6]);
            
            % pacdel = imread('./M-files/fire_features.jpg');
            % axis off          % Remove axis ticks and numbers
            % ha = axes('units','normalized','position',[0 0 1 1]);
            % imagesc(pacdel)
            % set(ha,'handlevisibility','off', 'visible','off')
            
            % Choose default command line output for gui_genFeatures
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: WaveType
        function WaveType_Callback(app, event)
            % --- Executes on selection change in WaveType.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to WaveType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = get(handles.WaveType,'String');
            pos = get(handles.WaveType,'Value');
            
            %%% define wavelet subtype
            switch  str{pos}
                case 'cMorlet'
                    strT={'cmor1-1.5','cmor1-1','cmor1-0.5','cmor1-1','cmor1-0.5','cmor1-0.1'};
                    set(handles.SubWaveType,'Value',1);
                    set(handles.SubWaveType,'Visible','on','String',strT);
                    set(handles.textWinType,'Visible','on','String','Select sub-Type of CWF:');
                case 'cGaussian'
                    for s=1:5
                        strT{s}=['cgau',num2str(s)];
                    end
                    set(handles.SubWaveType,'Value',1);
                    set(handles.SubWaveType,'Visible','on','String',strT);
                    set(handles.textWinType,'Visible','on','String','Select sub-Type of CWF:');
                case 'Shannon'
                    strT={'shan1-1.5','shan1-1','shan1-0.5','shan1-0.1','shan2-3'};
                    set(handles.SubWaveType,'Value',1);
                    set(handles.SubWaveType,'Visible','on','String',strT);
                    set(handles.textWinType,'Visible','on','String','Select sub-Type of CWF:');
                case 'FreqB-Spline'
                    strT={'fbsp1-1-1.5','fbsp1-1-1','fbsp1-1-0.5','fbsp2-1-1','fbsp2-1-0.5','fbsp2-1-0.1'};
                    set(handles.SubWaveType,'Value',1);
                    set(handles.SubWaveType,'Visible','on','String',strT);
                    set(handles.textWinType,'Visible','on','String','Select sub-Type of CWF:');
                case 'Symlets'
                    for s=2:8
                        strT{s-1}=['sym',num2str(s)];
                    end
                    set(handles.SubWaveType,'Value',1);
                    set(handles.SubWaveType,'Visible','on','String',strT);
                    set(handles.textWinType,'Visible','on','String','Select sub-Type of CWF:');
                case 'Daubechies'
                    for s=1:10
                        strT{s}=['db',num2str(s)];
                    end
                    set(handles.SubWaveType,'Value',1);
                    set(handles.SubWaveType,'Visible','on','String',strT);
                    set(handles.textWinType,'Visible','on','String','Select sub-Type of CWF:');
                otherwise
                    set(handles.SubWaveType,'Visible','off');
                    set(handles.textWinType,'Visible','off');
            end
            
            switch  str{pos}
                case {'Haar','Morlet','Symlets','Daubechies'}
                    set(handles.procWTcoef,'Value',1);
                    set(handles.procWTcoef,'Visible','on','String',{'none','ERSP','WTav','avWT','WTav-avWT'});
                case {'cMorlet','cGaussian','Shannon','FreqB-Spline'}
                    set(handles.procWTcoef,'Value',1);
                    set(handles.procWTcoef,'Visible','on','String',{'none','ITPC','ITLC','ERSP','WTav','avWT','WTav-avWT'});
                otherwise
                   set(handles.procWTcoef,'Value',1);
                   set(handles.procWTcoef,'Visible','on','String',{'none'});
            end
            
            guidata(hObject,handles)
        end

        % Value changed function: WinType
        function WinType_Callback(app, event)
            % --- Executes on selection change in WinType.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to WinType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns WinType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from WinType
            guidata(hObject,handles)
        end

        % Button pushed function: butDefFreq
        function butDefFreq_Callback(app, event)
            % --- Executes on button press in butDefFreq.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to butDefFreq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            if get(handles.corrFile,'UserData')
               iE=get(handles.corrFile,'UserData');
            else
               iE=paramGenFeatures.fileIndx;
            end
            
            if get(handles.butDefFreq,'Value')
                switch paramGenFeatures.fileHeader{iE}.cwtUnit
                    case 'scales'
                        set(handles.textScales,'Visible','on','String','Define Frequencies:');
                        paramGenFeatures.fileHeader{iE}.cwtUnit='freqs';
                        set(handles.butDefFreq,'String','Use Scales','Value',0);
                    case 'freqs'
                        set(handles.textScales,'Visible','on','String','Define Scales:');
                        paramGenFeatures.fileHeader{iE}.cwtUnit='scales';
                        set(handles.butDefFreq,'String','Use Frequencies','Value',0);
                end
            end
            
            
            guidata(hObject,handles)
        end

        % Button pushed function: corrFile
        function corrFile_Callback(app, event)
            % --- Executes on button press in corrFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to corrFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            set(handles.loadFile,'Visible','off');
            set(handles.repFile,'Visible','off');
            set(handles.delFile,'Visible','off');
            set(handles.corrFile,'Visible','off');
            set(handles.pushFinishSelection,'Visible','off');
            %set(handles.pushFinish,'Visible','off');
            
            
            [listNum, ok_click] = listdlg('PromptString','Select a file to correct', ...
                'SelectionMode','single', 'ListString',paramGenFeatures.fileName, ...
                'Name', 'Correct file');
            
            if ok_click
                set(handles.corrFile,'UserData',listNum); %%% correction true
            
                set(handles.textFile,'Visible','on','String',paramGenFeatures.fileName{listNum});
                set(handles.butPSD,'Visible','on','Value',0);
                %set(handles.butAR,'Visible','on','Value',0);
                set(handles.butCoherence,'Visible','on','Value',0);
                set(handles.butWT,'Visible','on','Value',0);
                set(handles.butNP,'Visible','on','Value',0);
                set(handles.butIRASA,'Visible','on','Value',0);
                set(handles.textMethod,'Visible','on','String','Select type of features to compute:');
            
                %%%% remove values
                switch paramGenFeatures.fileHeader{listNum}.featureType
                    case 'psd'
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'featureType');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'psdWindowType');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'psdSpectraType');
            
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'freqIn');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'zeroMean');
                        %paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'refType');
                        switch paramGenFeatures.fileHeader{listNum}.psdMethod
                            case 'fft'
                                % paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'nFFT');
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'nSubset');
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'detrendL');
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'fftNorm2Hz');
                            case 'welch'
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'detrendL');
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'nFFT');
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'psdWin_pwelch');
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'psdWinOverlap_pwelch');
                            case 'thomson'
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'detrendL');
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'nFFT');
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'nw_pmtm');
                        end
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'psdMethod');
                    case 'irasa'
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'irasaSpectraType');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'irasaWindowType');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'freqIn');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'zeroMean');
                        %paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'refType');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'nSubset');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'detrendL');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'rectOsc');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'fftNorm2Hz');
                    case 'coherence'
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'featureType');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'coherMethod');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'coherWindowType');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'coherWin');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'nFFT');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'freqIn');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'zeroMean');
                        %paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'refType');
                    case 'cwt'
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'featureType');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'cwtType');
                        switch paramGenFeatures.fileHeader{listNum}.cwtUnit
                            case 'scales'
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'cwtScales');
                            case 'freqs'
                                paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'cwtFreqs');
                        end
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'cwtUnit');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'procWTcoef');
                        paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'zeroMean');
            
                        %paramGenFeatures.fileHeader{listNum}=rmfield(paramGenFeatures.fileHeader{listNum},'refType');
                end
                handles=printParameters(app, paramGenFeatures,handles);
            
            else
                %hE=errordlg('No file selected');
                %uiwait(hE);
                set(handles.loadFile,'Visible','on');
                set(handles.repFile,'Visible','on');
                set(handles.delFile,'Visible','on');
                set(handles.corrFile,'Visible','on');
                set(handles.pushFinishSelection,'Visible','on');
            end
            guidata(hObject,handles)
        end

        % Button pushed function: delFile
        function delFile_Callback(app, event)
            % --- Executes on button press in delFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to delFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            set(handles.loadFile,'Visible','off');
            set(handles.repFile,'Visible','off');
            set(handles.corrFile,'Visible','off');
            set(handles.delFile,'Visible','off');
            set(handles.pushFinishSelection,'Visible','off');
            %set(handles.pushFinish,'Visible','off');
            
            
            [listNumAll, ok_click] = listdlg('PromptString','Select a file to correct', ...
                'SelectionMode','multiple', 'ListString',paramGenFeatures.fileName, ...
                'Name', 'Correct file');
            if ok_click
                if paramGenFeatures.fileIndx    == 1 %%% the first and the only one
                    paramGenFeatures.fileIndx    = 0;
                    paramGenFeatures.fileName    = [];
                    paramGenFeatures.pathName    = [];
                    paramGenFeatures=rmfield(paramGenFeatures,'fileHeader');
                    set(handles.loadFile,'Visible','on','Enable','on');
                    set(handles.loadParam,'Visible','on','Enable','on');
                    set(handles.listboxSum,'Visible','off');
                else
                    paramGenFeatures.fileIndx  = paramGenFeatures.fileIndx  - length(listNumAll);
                    paramGenFeatures.fileName(listNumAll) = [];
                    paramGenFeatures.pathName(listNumAll) = [];
                    paramGenFeatures.fileHeader(listNumAll) = [];
                    set(handles.loadFile,'Visible','on');
                    handles = printParameters(app, paramGenFeatures,handles);
                end
                if paramGenFeatures.fileIndx > 0
                    set(handles.loadFile,'Visible','on');
                    set(handles.repFile,'Visible','on');
                    set(handles.delFile,'Visible','on');
                    set(handles.corrFile,'Visible','on');
                    set(handles.pushFinishSelection,'Visible','on');
                end
            else
                %hE=errordlg('No file selected');
                %uiwait(hE);
                set(handles.loadFile,'Visible','on');
                set(handles.repFile,'Visible','on');
                set(handles.delFile,'Visible','on');
                set(handles.corrFile,'Visible','on');
                set(handles.pushFinishSelection,'Visible','on');
            end
            guidata(hObject,handles)
        end

        % Size changed function: figure1
        function figure1_ResizeFcn(app, event)
            % --- Executes when figure1 is resized.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles,'listboxSum')
                set(handles.listboxSum,'Position',[0.59 0.01 0.4 0.98]);
               %  handles = printParameters(paramGenFeatures,handles,true) ;
            end
            guidata(hObject,handles)
        end

        % Button pushed function: loadFile
        function loadFile_Callback(app, event)
            % --- Executes on button press in loadFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to loadFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            set(handles.corrFile,'Visible','off');
            set(handles.repFile,'Visible','off');
            set(handles.delFile,'Visible','off');
            set(handles.pushFinishSelection,'Visible','off');
            %set(handles.pushFinish,'Visible','off');
            
            set(handles.loadFile,'Enable','off');
            set(handles.loadParam,'Enable','off');
            
            [fileName, pathName] = uigetfile ({'./Data/EpochedData/*.mat'},'Open File');
            pathName=getRelPath(pathName);


            if isfield(paramGenFeatures,'fileIndx') && sum(strcmp(paramGenFeatures.fileName,fileName)) && sum(strcmp(paramGenFeatures.pathName,pathName))
                hE=warndlg('This file is already in .... !!','Warning - file alrady in');
                uiwait(hE);
                toDo = 3;
            elseif fileName
                %%% load header file
                strF=strcat(pathName,fileName);
                load(strF,'header')
                if ~exist('header','var')
                    hE=errordlg('Header is not present in the file ...','Error - header missing');
                    uiwait(hE);
                    toDo = 1;
                else
                    if isfield(paramGenFeatures,'fileIndx')
                        paramGenFeatures.fileIndx = paramGenFeatures.fileIndx + 1;
                    else
                        paramGenFeatures.fileIndx = 1;
                    end
                    paramGenFeatures.fileName{paramGenFeatures.fileIndx} = fileName;
                    paramGenFeatures.pathName{paramGenFeatures.fileIndx} = pathName;
                    paramGenFeatures.fileHeader{paramGenFeatures.fileIndx} = header;
                    toDo = 2;
                end
            elseif  isfield(paramGenFeatures,'fileIndx')
                toDo = 3;
            else
                toDo = 1;
            end
            
            switch toDo
                case 1
                  set(handles.loadFile,'Enable','on')
                  set(handles.loadParam,'Enable','on');
                case 2
                  handles = printParameters(app, paramGenFeatures,handles);
                  set(handles.loadFile,'Visible','off')
                  set(handles.loadParam,'Visible','off');
            
                  set(handles.textFile,'Visible','on','String',fileName)
                  set(handles.butPSD,'Visible','on','Value',0);
                  set(handles.butCoherence,'Visible','on','Value',0);
                  set(handles.butWT,'Visible','on','Value',0);
                  set(handles.butNP,'Visible','on','Value',0);
                  set(handles.butIRASA,'Visible','on','Value',0);
                  set(handles.textMethod,'Visible','on','String','Select type of features to compute:');
                case 3
                    set(handles.loadFile,'Visible','on','Enable','on');
                    set(handles.pushFinishSelection,'Visible','on');
                    set(handles.repFile,'Visible','on');
                    set(handles.corrFile,'Visible','on');
                    set(handles.delFile,'Visible','on');
            end
            
            
            guidata(hObject,handles)
        end

        % Button pushed function: loadParam
        function loadParam_Callback(app, event)
            % --- Executes on button press in loadParam.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to loadParam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            set(handles.loadFile,'Enable','off');
            set(handles.loadParam,'Enable','off');
            
            [fileName, pathName] = uigetfile ({'./ParameterFiles/*.mat'},'Open File');
            pathName=getRelPath(pathName);
            
            if fileName
                %%% load parameters
                strF=strcat(pathName,fileName);
                warning off
                s=load(strF,'paramGenFeatures');
                warning on
            
                if ~isfield(s,'paramGenFeatures')
                    hE=msgbox([fileName,' is not a correct parameter''s file'],'Error - not parameter''s file','error');
                    uiwait(hE);
                    set(handles.loadFile,'Enable','on');
                    set(handles.loadParam,'Enable','on');
                else
                    paramGenFeatures=s.paramGenFeatures;
                    %handles = printParameters(paramGenFeatures, handles);
                    %%%% reset parameters to run new file
                    hNames=fieldnames(handles);
                    %%%%% resetting values
                    %%%% clear some properties
            
                    for i=1:length(hNames)
                        if ~((strcmp(hNames{i},'figure1')) | (strcmp(hNames{i},'File')) | ...
                                (strcmp(hNames{i},'Open')) | (strcmp(hNames{i},'output')))
                            hTmp=strcat('handles.',hNames{i});
                            if isprop(eval(hTmp),'Visible')
                                set(eval(hTmp),'Visible','off')
                            end
                            if isprop(eval(hTmp),'Value')
                                set(eval(hTmp),'Value',0)
                            end
                            if isprop(eval(hTmp),'UserData')
                                set(eval(hTmp),'UserData',[])
                            end
                        end
                    end
                    % disp('ok')
                    %%%% open print window for sumarry
                    handles = printParameters(app, paramGenFeatures,handles);
            
                    set(handles.loadParam,'Visible','off');
                    set(handles.loadFile,'Visible','on','Enable','on');
                    set(handles.repFile,'Visible','on');
                    set(handles.corrFile,'Visible','on');
                    set(handles.delFile,'Visible','on');
                    set(handles.pushFinishSelection,'Visible','on');
                    % refresh
                end
            else
                set(handles.loadFile,'Visible','on','Enable','on');
                set(handles.loadParam,'Visible','on','Enable','on');
            end
            
            guidata(hObject,handles)
        end

        % Value changed function: psdSpecType
        function psdSpecType_Callback(app, event)
            % --- Executes on selection change in psdSpecType.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to psdSpecType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns psdSpecType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from psdSpecType
            guidata(hObject,handles)
        end

        % Button pushed function: pushFinishSelection
        function pushFinishSelection_Callback(app, event)
            % --- Executes on button press in pushFinishSelection.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushFinishSelection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.loadFile,'Visible','off');
            set(handles.delFile,'Visible','off');
            set(handles.corrFile,'Visible','off');
            set(handles.repFile,'Visible','off');
            set(handles.pushFinishSelection,'Visible','off');
            
            set(handles.pushGenFeatures,'Visible','on');
            set(handles.pushSaveParam,'Visible','on');
            set(handles.pushFinish,'Visible','on');
            set(handles.pushGoBack,'Visible','on');
        end

        % Button pushed function: pushFinish
        function pushFinish_Callback(app, event)
            % --- Executes on button press in pushFinish.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushFinish (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            set(handles.pushGenFeatures,'Visible','on');
            set(handles.pushSaveParam,'Visible','on');
            
            %pSave= get(handles.pushSaveParam,'UserData') ;
            
            %if pSave
            if isfield(paramGenFeatures,'paramSaveFile')
                save(paramGenFeatures.paramSaveFile,'paramGenFeatures');
                close(handles.figure1);
            else
                hD=questdlg('Parameters not saved .... Continue anyway?', ...
                    'Parameters not saved !','yes','no','no');
                switch hD
                    case 'yes'
                        close(handles.figure1);
                    case 'no'
                        %%% do nothing here, keep loop
                end
            end
        end

        % Button pushed function: pushGenFeatures
        function pushGenFeatures_Callback(app, event)
            % --- Executes on button press in pushGenFeatures.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushGenFeatures (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            set(handles.pushGenFeatures,'Enable','off');
            set(handles.pushSaveParam,'Enable','off');
            set(handles.pushFinish,'Enable','off');
            set(handles.pushGoBack,'Enable','off');
            
            pathName = uigetdir('./Data/FeaturesData','Select folder where to save epoched data ...');
            pathName=getRelPath(pathName);
            
            if pathName
                %hW=warndlg('Working ..... ');
                paramGenFeatures.pathFeaturesData = pathName;
                genFeatures(paramGenFeatures);
                %close(hW)
            end
            
            set(handles.pushGenFeatures,'Enable','on');
            set(handles.pushSaveParam,'Enable','on');
            set(handles.pushFinish,'Enable','on');
            set(handles.pushGoBack,'Enable','on');
            
            guidata(hObject,handles)
        end

        % Button pushed function: pushGoBack
        function pushGoBack_Callback(app, event)
            % --- Executes on button press in pushGoBack.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushGoBack (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.pushGenFeatures,'Visible','off');
            set(handles.pushSaveParam,'Visible','off');
            set(handles.pushFinish,'Visible','off');
            set(handles.pushGoBack,'Visible','off');
            
            set(handles.loadFile,'Visible','on');
            set(handles.repFile,'Visible','on');
            set(handles.corrFile,'Visible','on');
            set(handles.delFile,'Visible','on');
            set(handles.pushFinishSelection,'Visible','on');
            
            
            guidata(hObject,handles)
        end

        % Button pushed function: pushOK2
        function pushOK2_Callback(app, event)
            % --- Executes on button press in pushOK2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushOK2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            if get(handles.corrFile,'UserData')
               iE=get(handles.corrFile,'UserData');
            else
               iE=paramGenFeatures.fileIndx;
            end
            
            set(handles.textMethod,'Visible','off');
            set(handles.textHz,'Visible','off');
            set(handles.rangeHz,'Visible','off');
            set(handles.pushOK2,'Visible','off');
            set(handles.textFile,'Visible','off');
            
            %%%% freq in
            switch paramGenFeatures.fileHeader{iE}.featureType
                case {'psd','coherence','irasa'}
                    ss=get(handles.rangeHz,'String');
                    ss=['{',ss,'}'];
                    paramGenFeatures.fileHeader{iE}.freqIn= eval(ss);
            end
            %%%% zero-mean
            if get(handles.checkZeroMean,'Value')
                paramGenFeatures.fileHeader{iE}.zeroMean = true;
            else
                paramGenFeatures.fileHeader{iE}.zeroMean = false;
            end
            
            set(handles.checkZeroMean,'Visible','off','Value',1);
            
            handles = printParameters(app, paramGenFeatures,handles);
            
            set(handles.loadFile,'Visible','on','Enable','on');
            set(handles.repFile,'Visible','on');
            set(handles.corrFile,'Visible','on');
            set(handles.delFile,'Visible','on');
            set(handles.pushFinishSelection,'Visible','on');
            
            
            guidata(hObject,handles)
        end

        % Button pushed function: pushOK_1
        function pushOK_1_Callback(app, event)
            % --- Executes on button press in pushOK_1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushOK_1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            if get(handles.corrFile,'UserData')
               iE=get(handles.corrFile,'UserData');
            else
               iE=paramGenFeatures.fileIndx;
            end
            
            
            set(handles.textMethod,'Visible','off');
            set(handles.textWinType,'Visible','off');
            %set(handles.textSpecType,'Visible','off');
            set(handles.textParam,'Visible','off');
            set(handles.pushOK_1,'Visible','off');
            set(handles.selMethod,'Visible','off');
            set(handles.WinType,'Visible','off');
            %set(handles.psdSpecType,'Visible','off');
            set(handles.tableParam,'Visible','off');
            
            %%%% cwt
            set(handles.textScales,'Visible','off');
            set(handles.WaveType,'Visible','off');
            set(handles.SubWaveType,'Visible','off');
            set(handles.ScaleTab,'Visible','off');
            set(handles.procWTcoef,'Visible','off');
            set(handles.butDefFreq,'Visible','off');
            
            switch paramGenFeatures.fileHeader{iE}.featureType
                case 'psd'
                    set(handles.textSpecType,'Visible','off');
                    set(handles.psdSpecType,'Visible','off');
                    %%% psd method
                    str = get(handles.selMethod,'String');
                    pos = get(handles.selMethod,'Value');
                    paramGenFeatures.fileHeader{iE}.psdMethod=str{pos};
                    %%% psd window type
                    str = get(handles.WinType,'String');
                    pos = get(handles.WinType,'Value');
                    paramGenFeatures.fileHeader{iE}.psdWindowType=str{pos};
                    %%% psd Spectra type
                    str = get(handles.psdSpecType,'String');
                    pos = get(handles.psdSpecType,'Value');
                    paramGenFeatures.fileHeader{iE}.psdSpectraType=str{pos};
            
                    %%%% table parameters
                    datParam = get(handles.tableParam,'Data');
                    switch paramGenFeatures.fileHeader{iE}.psdMethod
                        case 'fft'
            %                 if isnan(str2double(datParam{1,2}))
            %                     paramGenFeatures.fileHeader{iE}.nFFT=[];
            %                 else
            %                     paramGenFeatures.fileHeader{iE}.nFFT=str2double(datParam{1,2});
            %                 end
                            datParam = get(handles.tableParam,'Data');
                            if isnan(str2double(datParam{1,2}))
                                paramGenFeatures.fileHeader{iE}.nSubset=[];
                            else
                                paramGenFeatures.fileHeader{iE}.nSubset=str2double(datParam{1,2});
                            end
                            if ~strcmp(datParam{2,2},'yes') & ~strcmp(datParam{2,2},'no')
                                paramGenFeatures.fileHeader{iE}.detrendL=[];
                            else
                                paramGenFeatures.fileHeader{iE}.detrendL=(datParam{2,2});
                            end
                            if ~strcmp(datParam{3,2},'yes') & ~strcmp(datParam{3,2},'no')
                                paramGenFeatures.fileHeader{iE}.fftNorm2Hz=[];
                            else
                                paramGenFeatures.fileHeader{iE}.fftNorm2Hz=(datParam{3,2});
                            end
                        case 'welch'
                            if isnan(str2double(datParam{1,2}))
                                paramGenFeatures.fileHeader{iE}.psdWin_pwelch=[];
                            else
                                paramGenFeatures.fileHeader{iE}.psdWin_pwelch=str2double(datParam{1,2});
                            end
                            if isnan(str2double(datParam{2,2}))
                                paramGenFeatures.fileHeader{iE}.psdWinOverlap_pwelch=[];
                            else
                                paramGenFeatures.fileHeader{iE}.psdWinOverlap_pwelch = str2double(datParam{2,2});
                            end
                            if isnan(str2double(datParam{3,2}))
                                paramGenFeatures.fileHeader{iE}.nFFT=[];
                            else
                                paramGenFeatures.fileHeader{iE}.nFFT=str2double(datParam{3,2});
                            end
                            if ~strcmp(datParam{4,2},'yes') & ~strcmp(datParam{4,2},'no')
                                paramGenFeatures.fileHeader{iE}.detrendL=[];
                            else
                                paramGenFeatures.fileHeader{iE}.detrendL=(datParam{4,2});
                            end
            
                        case 'thomson'
                            if isnan(str2double(datParam{1,2}))
                                paramGenFeatures.fileHeader{iE}.nw_pmtm=[];
                            else
                                paramGenFeatures.fileHeader{iE}.nw_pmtm=str2double(datParam{1,2});
                            end
                            if isnan(str2double(datParam{2,2}))
                                paramGenFeatures.fileHeader{iE}.nFFT=[];
                            else
                                paramGenFeatures.fileHeader{iE}.nFFT=str2double(datParam{2,2});
                            end
                             if ~strcmp(datParam{3,2},'yes') & ~strcmp(datParam{3,2},'no')
                                paramGenFeatures.fileHeader{iE}.detrendL=[];
                            else
                                paramGenFeatures.fileHeader{iE}.detrendL=(datParam{3,2});
                            end
                    end
            
                case 'irasa'
                    set(handles.textSpecType,'Visible','off');
                    set(handles.psdSpecType,'Visible','off');
            
                    %%% irasa window type
                    str = get(handles.WinType,'String');
                    pos = get(handles.WinType,'Value');
                    paramGenFeatures.fileHeader{iE}.irasaWindowType=str{pos};
            
                    %%% irasa Spectra type
                    str = get(handles.psdSpecType,'String');
                    pos = get(handles.psdSpecType,'Value');
                    paramGenFeatures.fileHeader{iE}.irasaSpectraType=str{pos};
            
                    %%%% table parameters
                    datParam = get(handles.tableParam,'Data');
                    if isnan(str2double(datParam{1,2}))
                        paramGenFeatures.fileHeader{iE}.nSubset=[];
                    else
                        paramGenFeatures.fileHeader{iE}.nSubset=str2double(datParam{1,2});
                    end
                    %if isnan(str2double(datParam{2,2}))
                    %    paramGenFeatures.fileHeader{iE}.hset=[];
                    %else
                        paramGenFeatures.fileHeader{iE}.hset=datParam{2,2};
                    %end
                    if ~strcmp(datParam{3,2},'yes') & ~strcmp(datParam{3,2},'no')
                        paramGenFeatures.fileHeader{iE}.detrendL=[];
                    else
                        paramGenFeatures.fileHeader{iE}.detrendL=(datParam{3,2});
                    end
                    if ~strcmp(datParam{4,2},'yes') & ~strcmp(datParam{4,2},'no')
                        paramGenFeatures.fileHeader{iE}.rectOsc=[];
                    else
                        paramGenFeatures.fileHeader{iE}.rectOsc=datParam{4,2};
                    end
                    if ~strcmp(datParam{5,2},'yes') & ~strcmp(datParam{5,2},'no')
                        paramGenFeatures.fileHeader{iE}.fftNorm2Hz=[];
                    else
                        paramGenFeatures.fileHeader{iE}.fftNorm2Hz=datParam{5,2};
                    end
                case 'coherence'
                    set(handles.textSpecType,'Visible','off');
                    %set(handles.psdSpecType,'Visible','off');
                    %%% coher method
                    str = get(handles.selMethod,'String');
                    pos = get(handles.selMethod,'Value');
                    paramGenFeatures.fileHeader{iE}.coherMethod=str{pos};
                    %%% psd window type
                    str = get(handles.WinType,'String');
                    pos = get(handles.WinType,'Value');
                    if strcmpi(paramGenFeatures.fileHeader{iE}.coherMethod,'moody')
                        % automatically set window type to hann
                        paramGenFeatures.fileHeader{iE}.coherWindowType=str{3};
                    else
                        paramGenFeatures.fileHeader{iE}.coherWindowType=str{pos};
                    end
            
                    %%%% table parameters
                    datParam = get(handles.tableParam,'Data');
                    %%%%% same parameters for all three coherence methods
                    if isnan(str2double(datParam{1,2}))
                        paramGenFeatures.fileHeader{iE}.coherWin=[];
                    else
                        paramGenFeatures.fileHeader{iE}.coherWin = str2double(datParam{1,2});
                    end
                    if isnan(str2double(datParam{2,2}))
                        paramGenFeatures.fileHeader{iE}.coherWinOverlap = [];
                    else
                        paramGenFeatures.fileHeader{iE}.coherWinOverlap = str2double(datParam{2,2});
                    end
                    if isnan(str2double(datParam{3,2}))
                        paramGenFeatures.fileHeader{iE}.nFFT=[];
                    else
                        paramGenFeatures.fileHeader{iE}.nFFT=str2double(datParam{3,2});
                    end
            
                case 'cwt'
                    set(handles.textSpecType,'Visible','off');
                    %set(handles.psdSpecType,'Visible','off');
                    %%% coher method
                    str = get(handles.WaveType,'String');
                    pos = get(handles.WaveType,'Value');
            
                    %%% 'Haar','Morlet','Symlets','Daubechies'
                    switch str{pos}
                        case 'Haar'
                            paramGenFeatures.fileHeader{iE}.cwtType='haar';
                        case 'Morlet'
                            paramGenFeatures.fileHeader{iE}.cwtType='morl';
                        otherwise
                        %case {'Symlets','Daubechies'}
                            str1 = get(handles.SubWaveType,'String');
                            pos1 = get(handles.SubWaveType,'Value');
                            paramGenFeatures.fileHeader{iE}.cwtType=str1{pos1};
                    end
                    %%%% process WT coef
                    str = get(handles.procWTcoef,'String');
                    pos = get(handles.procWTcoef,'Value');
                    paramGenFeatures.fileHeader{iE}.procWTcoef=str{pos};
            
                    %%% scales
                    strS = get(handles.ScaleTab,'String');
                    switch paramGenFeatures.fileHeader{iE}.cwtUnit
                        case 'scales'
                            eval(['paramGenFeatures.fileHeader{iE}.cwtScales=',strS,';']);
                        case 'freqs'
                            eval(['paramGenFeatures.fileHeader{iE}.cwtFreqs=',strS,';']);
                    end
            
            end
            
            %%%% now open for the second selection step
            switch paramGenFeatures.fileHeader{iE}.featureType
                case {'psd','coherence','irasa'}
                    set(handles.textMethod,'Visible','on','String',{'Frequency band(s) to store: ';
                        '(e.g. [0,12] [14,15])'});
                    set(handles.textHz,'Visible','on');
                    if isfield(paramGenFeatures.fileHeader{iE},'sampleFreq')
                        switch paramGenFeatures.fileHeader{iE}.sampleFreqUnit
                            case 'Hz'
                                fsNyquist  = paramGenFeatures.fileHeader{iE}.sampleFreq/2;
                            case 'kHz'
                                fsNyquist  = paramGenFeatures.fileHeader{iE}.sampleFreq*500;
                        end
                    else
                        fsNyquist = 1/2;
                    end
                    strHz = ['[0 , ',num2str(fsNyquist),']'];
                    set(handles.rangeHz,'Visible','on','String',strHz);
                    set(handles.checkZeroMean,'Visible','on','String','Zero-mean prior spectra computaiton','Value',1);
                case {'cwt','dwt'}
                    set(handles.checkZeroMean,'Visible','on','String','Zero-mean prior wevelet decomposition');
            end
            
            set(handles.pushOK2,'Visible','on');
            
            handles = printParameters(app, paramGenFeatures,handles);
            
            
            guidata(hObject,handles)
        end

        % Button pushed function: pushSaveParam
        function pushSaveParam_Callback(app, event)
            % --- Executes on button press in pushSaveParam.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushSaveParam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            [fName, pathName] = uiputfile( {'*.mat','MATLAB File'}, ...
                                              'Save parameters as ...','./ParameterFiles/param_GenFeatures.mat');
            pathName=getRelPath(pathName);
            
            if fName
                str=strcat(pathName,fName);
                paramGenFeatures.paramSaveFile=str;
                handles = printParameters(app, paramGenFeatures,handles);
                save(str,'paramGenFeatures');
                % set(handles.pushSaveParam,'UserData',true);
            end
            guidata(hObject,handles)
        end

        % Value changed function: rangeHz
        function rangeHz_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rangeHz (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of rangeHz as text
            %        str2double(get(hObject,'String')) returns contents of rangeHz as a double
            guidata(hObject,handles)
        end

        % Button pushed function: repFile
        function repFile_Callback(app, event)
            % --- Executes on button press in repFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to repFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            set(handles.loadFile,'Visible','off');
            set(handles.repFile,'Visible','off');
            set(handles.corrFile,'Visible','off');
            set(handles.delFile,'Visible','off');
            set(handles.pushFinishSelection,'Visible','off');
            %set(handles.pushFinish,'Visible','off');
            
            
            [listNum, ok_click] = listdlg('PromptString','Select file which parameteres to replicate', ...
                'SelectionMode','single', 'ListString',paramGenFeatures.fileName, ...
                'Name', 'Select file to replicate');
            
            if ok_click
                [fileName, pathName,filtInd] = uigetfile ({'./Data/EpochedData/*.mat'},'Open File','MultiSelect', 'on');
                pathName=getRelPath(pathName);
            
                if filtInd
                    fileIn = false;strFileIn = [];
                    if iscell(fileName)
                        lenFile = length(fileName);
                        fileInd = ones(lenFile,1);
                        for i=1:lenFile
                            if sum(strcmp(paramGenFeatures.fileName,fileName{i})) && sum(strcmp(paramGenFeatures.pathName,pathName))
                                fileIn = true;
                                strFileIn = [strFileIn, ',', fileName{i}];
                                fileInd(i)=0;
                            end
                        end
                    else
                        lenFile = 1; fileInd = 1;
                        if sum(strcmp(paramGenFeatures.fileName,fileName)) && sum(strcmp(paramGenFeatures.pathName,pathName))
                            fileIn = true;
                            strFileIn = [strFileIn, ',', fileName];
                            fileInd = 0;
                        end
                    end
                    if fileIn
                        strFileIn = ['These file(s) are alrady in: ',strFileIn(2:end), '.... will not be added !!!'];
                        hE=errordlg(strFileIn,'Warning - file(s) alrady in');
                        uiwait(hE);
                    end
                    for i=1:lenFile
                        if fileInd(i) == 1
                            if iscell(fileName)
                                fName=fileName{i};
                            else
                                fName=fileName;
                            end
                            strF=strcat(pathName,fName);
                            load(strF,'header')
                            if ~exist('header','var')
                                strH = ['Header not present in the', fileName{i},' file ... will not be included'];
                                hE=errordlg(strH,'Error - header missing');
                                uiwait(hE);
                            else
                                iE = paramGenFeatures.fileIndx + 1;
                                paramGenFeatures.fileIndx = iE;
                                paramGenFeatures.fileHeader{iE} = header;
                                paramGenFeatures.fileName{iE}   = fName;
                                paramGenFeatures.pathName{iE}   = pathName;
                                %%% copy new header values
                                fNnew = fieldnames(paramGenFeatures.fileHeader{iE});
                                fNold = fieldnames(paramGenFeatures.fileHeader{listNum});
                                for fNi = 1:length(fNold)
                                    if ~strcmp(fNold{fNi},fNnew)
                                        str =['paramGenFeatures.fileHeader{iE}.',fNold{fNi},'=paramGenFeatures.fileHeader{listNum}.',fNold{fNi},';'];
                                        eval(str)
                                    end
                                end
                            end
                        end
                    end
                end
            end
            handles = printParameters(app, paramGenFeatures,handles);
            
            set(handles.loadFile,'Visible','on');
            set(handles.repFile,'Visible','on');
            set(handles.delFile,'Visible','on');
            set(handles.corrFile,'Visible','on');
            set(handles.pushFinishSelection,'Visible','on');
            
            
            guidata(hObject,handles)
        end

        % Value changed function: selMethod
        function selMethod_Callback(app, event)
            % --- Executes on selection change in selMethod.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selMethod (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global paramGenFeatures
            
            if get(handles.corrFile,'UserData')
               iE=get(handles.corrFile,'UserData');
            else
               iE=paramGenFeatures.fileIndx;
            end
            
            switch paramGenFeatures.fileHeader{iE}.featureType
                case 'psd'
                    str = get(handles.selMethod,'String');
                    pos = get(handles.selMethod,'Value');
                    switch str{pos}
                        case 'fft'
                            set(handles.textParam,'String','Parameters for fft (fft.m):', 'Visible','on');
                            % strD(1)={num2str(paramGenFeatures.fileHeader{iE}.nFFT)};
                            % datTab=[{'# of FFT points'} , {strD{1}}];
                            if isfield(paramGenFeatures.fileHeader{iE},'nSubset')
                                datTab=[{'# subsets'} , num2str(paramGenFeatures.fileHeader{iE}.nSubset) ; ...
                                    {'linear detrend'}, paramGenFeatures.fileHeader{iE}.detrendL;];
                            else
                                datTab=[{'# subsets'} , num2str(1) ; {'linear detrend (yes/no)'}, 'yes'];
                            end
                        case 'welch'
                            lenWin=round(paramGenFeatures.fileHeader{iE}.nFFT/8); %%% takes 1/8 of the epoch length
                            strD(1)={num2str(lenWin)};
                            lenWinOverlap = lenWin / 2 ; %%% 50% overlap
                            strD(2)={num2str(lenWinOverlap)};
                            strD(3)={num2str(round(paramGenFeatures.fileHeader{iE}.nFFT))};
                            if isfield(paramGenFeatures.fileHeader{iE},'detrendL')
                               strD(4)={(paramGenFeatures.fileHeader{iE}.detrendL)};
                            else
                                 strD(4)={'yes'};
                            end
                            datTab=[{'window length';'window overlap';'# of FFT points';'linear detrend (yes/no)'} , ...
                                {strD{1};strD{2};strD{3};strD{4}}];
                            % datTab=[{'window length';'window overlap';'# of FFT points'} , {strD{1};strD{2};strD{3}}];
                            set(handles.textParam,'String','Parameters for welch (pwelch.m):', 'Visible','on');
            
                        case 'thomson'
                            strD(1)={'4'};
                            strD(2)={num2str(round(paramGenFeatures.fileHeader{iE}.nFFT))};
                            if isfield(paramGenFeatures.fileHeader{iE},'detrendL')
                                strD(3)={(paramGenFeatures.fileHeader{iE}.detrendL)};
                            else
                                strD(3)={'yes'};
                            end
                            datTab=[{'time-bandwidth product';'# of FFT points';'linear detrend (yes/no)'} , ...
                                {strD{1};strD{2};strD{3}}];
                            % datTab=[{'time-bandwidth product';'# of FFT points'} , {strD{1};strD{2}}];
                            set(handles.textParam,'String','Parameters for thomson (pmtm.m):', 'Visible','on');
                    end
                    set(handles.tableParam,'Data',datTab);
                case 'coherence'
                    str = get(handles.selMethod,'String');
                    pos = get(handles.selMethod,'Value');
                    switch str{pos}
                        case 'cpsd'
                            set(handles.textParam,'String','Parameters for ''cpsd.m'':', 'Visible','on');
                        case 'mscohere'
                            lenWin=round(paramGenFeatures.fileHeader{iE}.nFFT/8); %%% takes 1/8 of the epoch length
                            set(handles.textParam,'String','Parameters for ''mscohere.m'':', 'Visible','on');
                        case 'tfestimate'
                            set(handles.textParam,'String','Parameters for ''tfestimate.m'':', 'Visible','on');
                    end
            end
            
            guidata(hObject,handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'gui_genFeatures';
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1_ResizeFcn, true);
            app.figure1.Tag = 'figure1';
            app.loadFile = uibutton(app.figure1, 'push');
            app.loadFile.ButtonPushedFcn = createCallbackFcn(app, @loadFile_Callback, true);
            app.loadFile.Tag = 'loadFile';
            app.loadFile.Text = 'Add File ';
            app.loadParam = uibutton(app.figure1, 'push');
            app.loadParam.ButtonPushedFcn = createCallbackFcn(app, @loadParam_Callback, true);
            app.loadParam.Tag = 'loadParam';
            app.loadParam.Text = 'Load Parameters ';
            app.textMethod = uilabel(app.figure1);
            app.textMethod.Tag = 'textMethod';
            app.textMethod.Visible = 'off';
            app.textMethod.Text = 'Select type of features to compute:';
            app.selMethod = uilistbox(app.figure1);
            app.selMethod.Items = {};
            app.selMethod.ValueChangedFcn = createCallbackFcn(app, @selMethod_Callback, true);
            app.selMethod.Tag = 'selMethod';
            app.selMethod.Visible = 'off';
            app.selMethod.Value = {};
            app.textWinType = uilabel(app.figure1);
            app.textWinType.Tag = 'textWinType';
            app.textWinType.Visible = 'off';
            app.textWinType.Text = 'Select window type:';
            app.WinType = uilistbox(app.figure1);
            app.WinType.Items = {};
            app.WinType.ValueChangedFcn = createCallbackFcn(app, @WinType_Callback, true);
            app.WinType.Tag = 'WinType';
            app.WinType.Visible = 'off';
            app.WinType.Value = {};
            app.textParam = uilabel(app.figure1);
            app.textParam.Tag = 'textParam';
            app.textParam.Visible = 'off';
            app.textParam.Text = 'Parameters:';
            app.pushOK_1 = uibutton(app.figure1, 'push');
            app.pushOK_1.ButtonPushedFcn = createCallbackFcn(app, @pushOK_1_Callback, true);
            app.pushOK_1.Tag = 'pushOK_1';
            app.pushOK_1.Visible = 'off';
            app.pushOK_1.Text = 'OK ';
            app.textFile = uilabel(app.figure1);
            app.textFile.Tag = 'textFile';
            app.textFile.Visible = 'off';
            app.textFile.Text = '';
            app.rangeHz = uieditfield(app.figure1, 'text');
            app.rangeHz.ValueChangedFcn = createCallbackFcn(app, @rangeHz_Callback, true);
            app.rangeHz.Tag = 'rangeHz';
            app.rangeHz.Visible = 'off';
            app.textHz = uilabel(app.figure1);
            app.textHz.Tag = 'textHz';
            app.textHz.Visible = 'off';
            app.textHz.Text = 'Hz';
            app.pushOK2 = uibutton(app.figure1, 'push');
            app.pushOK2.ButtonPushedFcn = createCallbackFcn(app, @pushOK2_Callback, true);
            app.pushOK2.Tag = 'pushOK2';
            app.pushOK2.Visible = 'off';
            app.pushOK2.Text = 'OK ';
            app.corrFile = uibutton(app.figure1, 'push');
            app.corrFile.ButtonPushedFcn = createCallbackFcn(app, @corrFile_Callback, true);
            app.corrFile.Tag = 'corrFile';
            app.corrFile.Visible = 'off';
            app.corrFile.Text = 'Correct File';
            app.delFile = uibutton(app.figure1, 'push');
            app.delFile.ButtonPushedFcn = createCallbackFcn(app, @delFile_Callback, true);
            app.delFile.Tag = 'delFile';
            app.delFile.Visible = 'off';
            app.delFile.Text = 'Delete File';
            app.repFile = uibutton(app.figure1, 'push');
            app.repFile.ButtonPushedFcn = createCallbackFcn(app, @repFile_Callback, true);
            app.repFile.Tag = 'repFile';
            app.repFile.Visible = 'off';
            app.repFile.Text = 'Add File (with exisitng param.) ';
            app.pushGenFeatures = uibutton(app.figure1, 'push');
            app.pushGenFeatures.ButtonPushedFcn = createCallbackFcn(app, @pushGenFeatures_Callback, true);
            app.pushGenFeatures.Tag = 'pushGenFeatures';
            app.pushGenFeatures.Visible = 'off';
            app.pushGenFeatures.Text = 'Generate Features';
            app.pushSaveParam = uibutton(app.figure1, 'push');
            app.pushSaveParam.ButtonPushedFcn = createCallbackFcn(app, @pushSaveParam_Callback, true);
            app.pushSaveParam.Tag = 'pushSaveParam';
            app.pushSaveParam.Visible = 'off';
            app.pushSaveParam.Text = 'Save Parameters';
            app.pushGoBack = uibutton(app.figure1, 'push');
            app.pushGoBack.ButtonPushedFcn = createCallbackFcn(app, @pushGoBack_Callback, true);
            app.pushGoBack.Tag = 'pushGoBack';
            app.pushGoBack.Visible = 'off';
            app.pushGoBack.Text = {'Go Back (to File Selection)'; ''; ''};
            app.checkZeroMean = uicheckbox(app.figure1);
            app.checkZeroMean.Tag = 'checkZeroMean';
            app.checkZeroMean.Visible = 'off';
            app.checkZeroMean.Text = 'Zero-mean prior spectra computation? ';
            app.checkZeroMean.Value = true;
            app.tableParam = uitable(app.figure1);
            app.tableParam.ColumnEditable = [false false];
            app.tableParam.Visible = 'off';
            app.tableParam.Tag = 'tableParam';
            app.textSpecType = uilabel(app.figure1);
            app.textSpecType.Tag = 'textSpecType';
            app.textSpecType.Visible = 'off';
            app.textSpecType.Text = 'Type of spectra:';
            app.psdSpecType = uilistbox(app.figure1);
            app.psdSpecType.Items = {};
            app.psdSpecType.ValueChangedFcn = createCallbackFcn(app, @psdSpecType_Callback, true);
            app.psdSpecType.Tag = 'psdSpecType';
            app.psdSpecType.Visible = 'off';
            app.psdSpecType.Value = {};
            app.pushFinishSelection = uibutton(app.figure1, 'push');
            app.pushFinishSelection.ButtonPushedFcn = createCallbackFcn(app, @pushFinishSelection_Callback, true);
            app.pushFinishSelection.Tag = 'pushFinishSelection';
            app.pushFinishSelection.Visible = 'off';
            app.pushFinishSelection.Text = 'File Selection - Done!';
            app.pushFinish = uibutton(app.figure1, 'push');
            app.pushFinish.ButtonPushedFcn = createCallbackFcn(app, @pushFinish_Callback, true);
            app.pushFinish.Tag = 'pushFinish';
            app.pushFinish.Visible = 'off';
            app.pushFinish.Text = 'Finish !';
            app.WaveType = uilistbox(app.figure1);
            app.WaveType.Items = {'Type of Wavelet'};
            app.WaveType.ValueChangedFcn = createCallbackFcn(app, @WaveType_Callback, true);
            app.WaveType.Tag = 'WaveType';
            app.WaveType.Visible = 'off';
            app.WaveType.Value = 'Type of Wavelet';
            app.ScaleTab = uieditfield(app.figure1, 'text');
            app.ScaleTab.Tag = 'ScaleTab';
            app.ScaleTab.Visible = 'off';
            app.ScaleTab.Value = '[1:64]';
            app.textScales = uilabel(app.figure1);
            app.textScales.Tag = 'textScales';
            app.textScales.Visible = 'off';
            app.textScales.Text = 'Define scales: ';
            app.SubWaveType = uilistbox(app.figure1);
            app.SubWaveType.Items = {'SubType of Wavelet'};
            app.SubWaveType.Tag = 'SubWaveType';
            app.SubWaveType.Visible = 'off';
            app.SubWaveType.Value = 'SubType of Wavelet';
            app.procWTcoef = uilistbox(app.figure1);
            app.procWTcoef.Items = {'Process WT Coeff'};
            app.procWTcoef.Tag = 'procWTcoef';
            app.procWTcoef.Visible = 'off';
            app.procWTcoef.Value = 'Process WT Coeff';
            app.textProcWTC = uilabel(app.figure1);
            app.textProcWTC.Tag = 'textProcWTC';
            app.textProcWTC.Visible = 'off';
            app.textProcWTC.Text = 'WT Coefficient Process ';
            app.butDefFreq = uibutton(app.figure1, 'push');
            app.butDefFreq.ButtonPushedFcn = createCallbackFcn(app, @butDefFreq_Callback, true);
            app.butDefFreq.Tag = 'butDefFreq';
            app.butDefFreq.Visible = 'off';
            app.butDefFreq.Text = 'Use Frequencies';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_genFeatures_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)gui_genFeatures_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end